classdef ScoreREMGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScoreREMGUIGNUGPL30UIFigure   matlab.ui.Figure
        Panel_4                       matlab.ui.container.Panel
        SegmentNumberEditFieldLabel   matlab.ui.control.Label
        SegmentNumberEditField        matlab.ui.control.EditField
        EMGLabel                      matlab.ui.control.Label
        RButton                       matlab.ui.control.Button
        O1M2Label                     matlab.ui.control.Label
        RButton_2                     matlab.ui.control.Button
        C3M2Label                     matlab.ui.control.Label
        RButton_3                     matlab.ui.control.Button
        F3M2Label                     matlab.ui.control.Label
        RButton_4                     matlab.ui.control.Button
        O2M1Label                     matlab.ui.control.Label
        RButton_5                     matlab.ui.control.Button
        C4M1Label                     matlab.ui.control.Label
        RButton_6                     matlab.ui.control.Button
        F4M1Label                     matlab.ui.control.Label
        RButton_7                     matlab.ui.control.Button
        ROCLabel                      matlab.ui.control.Label
        RButton_8                     matlab.ui.control.Button
        LOCLabel                      matlab.ui.control.Label
        RButton_9                     matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        Button_11                     matlab.ui.control.Button
        GoToEpochDropDown             matlab.ui.control.DropDown
        GoToEpochDropDownLabel        matlab.ui.control.Label
        EMGuVEditField                matlab.ui.control.NumericEditField
        EMGuVEditFieldLabel           matlab.ui.control.Label
        EEGuVEditField                matlab.ui.control.NumericEditField
        EEGuVEditFieldLabel           matlab.ui.control.Label
        EOGuVEditField                matlab.ui.control.NumericEditField
        EOGuVEditFieldLabel           matlab.ui.control.Label
        Button_6                      matlab.ui.control.Button
        Button_5                      matlab.ui.control.Button
        Panel_3                       matlab.ui.container.Panel
        OpendeletedtableButton        matlab.ui.control.Button
        Button_10                     matlab.ui.control.Button
        Button_9                      matlab.ui.control.Button
        Button_8                      matlab.ui.control.Button
        Button_7                      matlab.ui.control.Button
        LightsonEditField             matlab.ui.control.EditField
        LightsonEditFieldLabel        matlab.ui.control.Label
        LightsoffEditField            matlab.ui.control.EditField
        LightsoffEditFieldLabel       matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        GOButton                      matlab.ui.control.Button
        RETURNButton                  matlab.ui.control.Button
        EpochnoEditFieldLabel         matlab.ui.control.Label
        EpochnoEditField              matlab.ui.control.EditField
        VigilanceStateEditField       matlab.ui.control.EditField
        VigilanceStateEditFieldLabel  matlab.ui.control.Label
        TimeEditFieldLabel            matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.EditField
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes5_2                     matlab.ui.control.UIAxes
        UIAxes5_3                     matlab.ui.control.UIAxes
        UIAxes5_4                     matlab.ui.control.UIAxes
        UIAxes5_5                     matlab.ui.control.UIAxes
        UIAxes5_6                     matlab.ui.control.UIAxes
        UIAxes5_7                     matlab.ui.control.UIAxes
        UIAxes5_8                     matlab.ui.control.UIAxes
        UIAxes5_9                     matlab.ui.control.UIAxes
        UIAxes10                      matlab.ui.control.UIAxes
    end

    % "ScoreREM Copyright (C) 2024  Kiran K G Ravindran.
    % This program is free software: you can redistribute it and/or modify it
    % under the terms of the GNU General Public License as published by the
    % Free Software Foundation, either version 3 of the License,
    % or (at your option) any later version. This program is distributed
    % WITHOUT ANY WARRANTY.  See the GNU General Public License for more details
    % (https://www.gnu.org/licenses/)
    properties (Access = private)
        % Description
        i;j;ichan;iseg;jj
        edf_full;path;Mark_full
        FileName;ismarker;isedfOK;isSelectedChannelsOK
        Hyp_full;Var_tab;Y;Hypnogram_Str
        Fs_EEG;Fs_EOG;Fs_EMG
        FC_EEG;FC_EOG;FC_EMG;FC_Power
        E;E_EOG;E_EMG
        Epoch_time;epoch_len;end_len
        num_chan_find;channel_names;Hypnogram
        Fs;seg_len;time_e
        time;vertlines;epoch_segs
        Epoch_labels;tab;Row_num
        Column_num;Evar_name;Evar_name_other
        Label_scored;scored_epoch;scored_epoch_time
        savepoint1;temp_tab;temp_high;temp_hyp_label
        temp_data_seg;W_label;vert_ind;temp_epoch_data
        Temp_data_seg;chan_all;seg_all
        val; goen;dname_auto
        mi_EEG;mi_EOG;mi_EMG
        REM_indices;REM_i;s_en
        python_true;Available_channels;selected_channel_names
        auto_hypno;auto_start_time;YASA_Autoscorer_channels
        Marker_error; Hypnogram_error;selected_hypnomap
        edf_start_time;edf_end_time
        Event_labels;REM_score_label;time_em
    end

    methods (Access = private)

        function Epoch_plot(app)
            app.jj=0;
            update_seg(app)
            app.s_en=0;

            uitablehandle = findall(groot,'type','uitable');
            tt2=(strings(app.Row_num,app.Column_num));
            tt2(app.temp_tab==1)="A";
            tt2(app.temp_tab==2)="PR";
            tt2(app.temp_tab==3)="TR";
            set(uitablehandle,'Data',tt2)
            temp_epoch=(app.E(:,:,app.j));
            temp_epoch_EOG=(app.E_EOG(:,:,app.j));
            temp_epoch_EMG=(app.E_EMG(:,app.j));
            app.temp_epoch_data=temp_epoch_EOG;
            % EEG
            for ii=1:length(app.Evar_name)
                plot((eval(app.Evar_name{ii})),app.time_e,temp_epoch(:,ii));
                xlim((eval(app.Evar_name{ii})),[app.time_e(1) app.time_e(end)]);
                mi=-app.mi_EEG;
                ma=app.mi_EEG;
                ylim((eval(app.Evar_name{ii})),[mi ma]);
                ax=(eval(app.Evar_name{ii}));
                ax.Interactions = zoomInteraction('Dimensions','y');
                hold((eval(app.Evar_name{ii})), 'on');
                for jjj=1:length(app.vertlines)
                    a=(eval(app.Evar_name{ii}));
                    xline(a,app.vertlines(jjj),'-.b');
                end
            end
            % Others
            for ij=1:length(app.Evar_name_other)
                if ij<3
                    plot((eval(app.Evar_name_other{ij})),app.time_e,temp_epoch_EOG(:,ij));
                    mi=-app.mi_EOG;
                    ma=app.mi_EOG;
                    ylim((eval(app.Evar_name_other{ij})),[mi ma]);
                else
                    plot((eval(app.Evar_name_other{ij})),app.time_em,temp_epoch_EMG(:,1));
                    mi=-app.mi_EMG;
                    ma=app.mi_EMG;
                    ylim((eval(app.Evar_name_other{ij})),[mi ma]);
                end
                xlim((eval(app.Evar_name_other{ij})),[app.time_e(1) app.time_e(end)]);
                ax=(eval(app.Evar_name_other{ij}));
                ax.Interactions = zoomInteraction('Dimensions','y');
                hold((eval(app.Evar_name_other{ij})), 'on');
                for jjj=1:length(app.vertlines)
                    a=(eval(app.Evar_name_other{ij}));
                    xline(a,app.vertlines(jjj),'-.b');
                end
            end
            app.temp_hyp_label=app.Hypnogram(app.j);
            Hypnogram_label(app)
            app.TimeEditField.Value=string(app.Epoch_time(app.j));
            app.TimeEditField.FontColor='r';
            app.TimeEditField.FontWeight='bold';
            app.TimeEditField.Editable="off";
            %
            app.EpochnoEditField.Value=num2str(app.j);
            app.EpochnoEditField.FontColor='r';
            app.EpochnoEditField.FontWeight='bold';
            app.EpochnoEditField.Editable="off";
            %
            app.Temp_data_seg=app.Y(:,:,(1+((app.j-1)*app.Column_num)):(app.j*app.Column_num));
        end

        function high_auto(app)
            if ~isempty(all(app.temp_tab))
                all_Seg_ind=find(all(app.temp_tab==1));
                all_Ch_ind=find(all(app.temp_tab==1,2));
                if ~isempty(all_Ch_ind)
                    for ii=1:length(all_Ch_ind)
                        app.chan_all=all_Ch_ind(ii);
                        highlight_chan(app)
                    end
                end
                if ~isempty(all_Seg_ind)
                    for ii=1:length(all_Seg_ind)
                        app.seg_all=all_Seg_ind(ii);
                        highlight_seg(app)
                    end
                end
                %
                [c]=find(app.temp_tab'==1);
                [r(:,1),r(:,2)]=find(app.temp_tab==1);
                if length(c)==length(r)
                    if ~isempty(c)
                        for ii=1:length(c)
                            if ~ismember(r(ii,1),all_Ch_ind)
                                app.ichan=r(ii,1);
                            else
                                app.ichan=0;
                            end
                            if ~ismember(r(ii,2),all_Seg_ind)
                                app.iseg=r(ii,2);
                            else
                                app.iseg=0;
                            end
                            if (app.ichan~=0&&app.iseg~=0)
                                highlight(app)
                            end
                        end
                    end
                end
            end
        end

        function Hypnogram_label(app)
            switch app.temp_hyp_label
                case 1
                    app.VigilanceStateEditField.Value='N3';
                    app.VigilanceStateEditField.FontColor='r';
                    app.VigilanceStateEditField.FontWeight='bold';
                case 2
                    app.VigilanceStateEditField.Value='N2';
                    app.VigilanceStateEditField.FontColor='r';
                    app.VigilanceStateEditField.FontWeight='bold';
                case 3
                    app.VigilanceStateEditField.Value='N1';
                    app.VigilanceStateEditField.FontColor='r';
                    app.VigilanceStateEditField.FontWeight='bold';
                    app.VigilanceStateEditField.Editable="off";
                case 4
                    app.VigilanceStateEditField.Value='REM';
                    app.VigilanceStateEditField.FontColor='r';
                    app.VigilanceStateEditField.FontWeight='bold';
                    app.VigilanceStateEditField.Editable="off";
                case 5
                    app.VigilanceStateEditField.Value='WAKE';
                    app.VigilanceStateEditField.FontColor='r';
                    app.VigilanceStateEditField.FontWeight='bold';
                    app.VigilanceStateEditField.Editable="off";
                otherwise
                    app.VigilanceStateEditField.Value='ARTIFACT';
                    app.VigilanceStateEditField.FontColor='r';
                    app.VigilanceStateEditField.FontWeight='bold';
                    app.VigilanceStateEditField.Editable="off";
            end
        end

        function highlight(app)
            Fss=app.Fs;
            L=app.seg_len*Fss;
            m=(1+((app.iseg-1)*L)) ;     n=(L+((app.iseg-1)*L));
            Temp=app.temp_epoch_data;
            mi=-app.mi_EOG;
            ma=app.mi_EOG;
            if app.temp_high==1
                plot((eval(app.Evar_name_other{app.ichan})),app.time_e(m:n),Temp(m:n,app.ichan),"Color",'r');
                ylim((eval(app.Evar_name_other{app.ichan})),[mi ma]);
            else
                plot((eval(app.Evar_name_other{app.ichan})),app.time_e(m:n),Temp(m:n,app.ichan),"Color",[0.07,0.62,1.00]);
                ylim((eval(app.Evar_name_other{app.ichan})),[mi ma]);
            end
        end

        function highlight_seg(app)
            Fss=app.Fs;  L=app.seg_len*Fss;
            m=(1+((app.seg_all-1)*L)) ;    n=(L+((app.seg_all-1)*L));
            Temp=app.temp_epoch_data;
            if app.temp_high==1
                for ii=1:app.Row_num
                    a=Temp(:,ii);
                    mi=-app.mi_EOG;
                    ma=app.mi_EOG;
                    plot((eval(app.Evar_name_other{ii})),app.time_e(m:n),a(m:n),"Color",'r');
                    ylim((eval(app.Evar_name_other{ii})),[mi ma]);
                end
            else
                for ii=1:app.Row_num
                    a=Temp(:,ii);
                    mi=-app.mi_EOG;
                    ma=app.mi_EOG;
                    plot((eval(app.Evar_name_other{ii})),app.time_e(m:n),a(m:n),"Color",'k');
                    ylim((eval(app.Evar_name_other{ii})),[mi ma]);
                end
            end
        end

        function highlight_chan(app)
            Temp=app.temp_epoch_data; a=Temp(:,app.chan_all);
            mi=-app.mi_EOG;
            ma=app.mi_EOG;
            if app.temp_high==1
                plot((eval(app.Evar_name_other{app.chan_all})),app.time_e,a,"Color",'r');
                ylim((eval(app.Evar_name_other{app.chan_all})),[mi ma]);
            else
                plot((eval(app.Evar_name_other{app.chan_all})),app.time_e,a,"Color",'k');
                ylim((eval(app.Evar_name_other{app.chan_all})),[mi ma]);
            end
        end

        function highlight_REM(app)
            Fss=app.Fs;
            L=app.seg_len*Fss;
            m=(1+((app.iseg-1)*L)) ;     n=(L+((app.iseg-1)*L));
            Temp=app.temp_epoch_data;
            mi=-app.mi_EOG;
            ma=app.mi_EOG;
            if app.temp_high==2
                plot((eval(app.Evar_name_other{app.ichan})),app.time_e(m:n),Temp(m:n,app.ichan),"Color",'#FFA500');
                ylim((eval(app.Evar_name_other{app.ichan})),[mi ma]);
            else
                plot((eval(app.Evar_name_other{app.ichan})),app.time_e(m:n),Temp(m:n,app.ichan),"Color",[0.07,0.62,1.00]);
                ylim((eval(app.Evar_name_other{app.ichan})),[mi ma]);
            end
        end

        function highlight_all(app)
            Temp=app.temp_epoch_data;
            for jjj=1:app.Row_num
                a=Temp(:,jjj);
                mi=-app.mi_EOG;
                ma=app.mi_EOG;
                if app.temp_high==1
                    plot((eval(app.Evar_name_other{jjj})),app.time_e,a,"Color",'r');
                    ylim((eval(app.Evar_name_other{jjj})),[mi ma]);
                else
                    plot((eval(app.Evar_name_other{jjj})),app.time_e,a,"Color",'k');
                    ylim((eval(app.Evar_name_other{jjj})),[mi ma]);
                    for k=1:length(app.gen_handle)
                        app.gen_handle(k).Value=0;
                    end
                end
            end
        end

        function update_plot(app)
            data=squeeze(app.Temp_data_seg(:,:,app.iseg));
            plot(app.UIAxes10,app.time,data);
            legend(app.UIAxes10,{'EOG-L','EOG-R'});
            ylim(app.UIAxes10,[min(min(data))-10 max(max(data))+10]);
        end
        function update_linesA(app)
            % Mark the red lines in EOG
            for jjj=1:length(app.Evar_name_other)-1
                a=(eval(app.Evar_name_other{jjj}));
                if app.jj<30
                    xline(a,app.vertlines(app.jj),'-.r');
                end
                if app.jj>1
                    xline(a,app.vertlines(app.jj-1),'-.r');
                end
                if app.jj<29
                    xline(a,app.vertlines(app.jj+1),'-.b');
                end
            end
        end
        function update_linesF(app)

            % Mark the red lines in EOG
            for jjj=1:length(app.Evar_name_other)-1
                a=(eval(app.Evar_name_other{jjj}));
                if app.jj>1
                    xline(a,app.vertlines(app.jj-1),'-.r');
                end
                if app.jj<30
                    xline(a,app.vertlines(app.jj),'-.r');
                end
                if app.jj>2
                    xline(a,app.vertlines(app.jj-2),'-.b');
                end
            end
        end
        function update_seg(app)
            app.SegmentNumberEditField.Value=string(app.jj);
        end

        function autosave(app)
            name=app.FileNameEditField.Value;
            name=erase(name,'.edf');
            Labels=app.Label_scored;
            Final_epoch=app.scored_epoch;
            Final_epoch_time=app.scored_epoch_time;
            Hypnogram_temp=app.Hypnogram;
            valueSet= (["N3","N2","N1","R","W","A"]);
            keySet= [1 2 3 4 5 6];
            M = containers.Map(keySet,valueSet);
            Hypnogram_string=string(arrayfun(@(x)(M(x)),Hypnogram_temp,'UniformOutput',false));
            Hypnogram_Tab=timetable(Hypnogram_string,'RowTimes',app.Epoch_time);
            REM_index=app.REM_indices;
            save(strcat(app.dname_auto,'\',name,'_scoredREMlabels_auto.mat'),'Labels','Final_epoch','Final_epoch_time','Hypnogram_Tab','REM_index');
        end

        function REM_Go(app)
            app.jj=0;update_seg(app);
            app.temp_high=1;
            % making sure the data is not lost when moving back and
            % forth
            if app.j<app.savepoint1
                app.Label_scored(:,:,app.j)=(app.temp_tab);
                app.scored_epoch=app.j;
                app.scored_epoch_time=app.Epoch_time(app.j);
                app.j=app.REM_indices(app.REM_i);
                % refresh the plots
                for ii=1:length(app.Evar_name)
                    hold((eval(app.Evar_name{ii})), 'off');
                end
                for ii=1:length(app.Evar_name_other)
                    hold((eval(app.Evar_name_other{ii})), 'off');
                end
                app.temp_tab= squeeze(app.Label_scored(:,:,app.j));
                Epoch_plot(app);
                %
                if ~isempty(all(app.temp_tab))
                    all_Seg_ind=find(all(app.temp_tab==1));
                    all_Ch_ind=find(all(app.temp_tab==1,2));
                    if ~isempty(all_Ch_ind)
                        for ii=1:length(all_Ch_ind)
                            app.chan_all=all_Ch_ind(ii);
                            highlight_chan(app)
                        end
                    end
                    if ~isempty(all_Seg_ind)
                        for ii=1:length(all_Seg_ind)
                            app.seg_all=all_Seg_ind(ii);
                            highlight_seg(app)
                        end
                    end
                    %
                    [c]=find(app.temp_tab'==1);
                    [r(:,1),r(:,2)]=find(app.temp_tab==1);
                    if length(c)==length(r)
                        if ~isempty(c)
                            for ii=1:length(c)
                                if ~ismember(r(ii,1),all_Ch_ind)
                                    app.ichan=r(ii,1);
                                else
                                    app.ichan=0;
                                end
                                if ~ismember(r(ii,2),all_Seg_ind)
                                    app.iseg=r(ii,2);
                                else
                                    app.iseg=0;
                                end
                                if (app.ichan~=0&&app.iseg~=0)
                                    highlight(app)
                                end
                            end
                        end
                    end
                    %% if EOG/REM
                    app.temp_high=2;
                    for ii=1:length(app.Evar_name_other)
                        hold((eval(app.Evar_name_other{ii})), 'on');
                    end
                    [c1]=find(app.temp_tab'==2);
                    [r1(:,1),r1(:,2)]=find(app.temp_tab==2);
                    if length(c1)==length(r1)
                        if ~isempty(c1)
                            for ii=1:length(c1)
                                if ~ismember(r1(ii,1),all_Ch_ind)
                                    app.ichan=r1(ii,1);
                                else
                                    app.ichan=0;
                                end
                                if ~ismember(r1(ii,2),all_Seg_ind)
                                    app.iseg=r1(ii,2);
                                else
                                    app.iseg=0;
                                end
                                if (app.ichan~=0&&app.iseg~=0)
                                    highlight_REM(app)
                                end
                            end
                        end
                    end
                    %
                    app.temp_high=3;
                    [c2]=find(app.temp_tab'==3);
                    [r2(:,1),r2(:,2)]=find(app.temp_tab==3);
                    if length(c2)==length(r2)
                        if ~isempty(c2)
                            for ii=1:length(c2)
                                if ~ismember(r2(ii,1),all_Ch_ind)
                                    app.ichan=r2(ii,1);
                                else
                                    app.ichan=0;
                                end
                                if ~ismember(r2(ii,2),all_Seg_ind)
                                    app.iseg=r2(ii,2);
                                else
                                    app.iseg=0;
                                end
                                if (app.ichan~=0&&app.iseg~=0)
                                    highlight_REM2(app)
                                end
                            end
                        end
                    end
                end
                % When going through new epochs
            else
                autosave(app);
                app.Label_scored(:,:,app.j)=(app.temp_tab);
                app.scored_epoch=app.j;
                app.savepoint1=app.j;
                app.scored_epoch_time=app.Epoch_time(app.j);
                app.j=app.REM_indices(app.REM_i);
                for ii=1:length(app.Evar_name)
                    hold((eval(app.Evar_name{ii})), 'off');
                end
                for ii=1:length(app.Evar_name_other)
                    hold((eval(app.Evar_name_other{ii})), 'off');
                end
                app.temp_tab=zeros(app.Row_num,app.Column_num);
                Epoch_plot(app);
            end
            cla(app.UIAxes10)
            %             cla(app.UIAxes3)
        end

        function REM_GoBack(app)
            app.jj=0;
            update_seg(app)
            app.temp_high=1;
            app.j=app.REM_indices(app.REM_i);
            app.temp_tab= squeeze(app.Label_scored(:,:,app.j));
            for ii=1:length(app.Evar_name)
                hold((eval(app.Evar_name{ii})), 'off');
            end
            for ii=1:length(app.Evar_name_other)
                hold((eval(app.Evar_name_other{ii})), 'off');
            end
            Epoch_plot(app);
            % all chan or segment
            if ~isempty(all(app.temp_tab))
                all_Seg_ind=find(all(app.temp_tab==1));
                all_Ch_ind=find(all(app.temp_tab==1,2));
                if ~isempty(all_Ch_ind)
                    for ii=1:length(all_Ch_ind)
                        app.chan_all=all_Ch_ind(ii);
                        highlight_chan(app)
                    end
                end
                if ~isempty(all_Seg_ind)
                    for ii=1:length(all_Seg_ind)
                        app.seg_all=all_Seg_ind(ii);
                        highlight_seg(app)
                    end
                end
                %
                [c]=find(app.temp_tab'==1);
                [r(:,1),r(:,2)]=find(app.temp_tab==1);
                if length(c)==length(r)
                    if ~isempty(c)
                        for ii=1:length(c)
                            if ~ismember(r(ii,1),all_Ch_ind)
                                app.ichan=r(ii,1);
                            else
                                app.ichan=0;
                            end
                            if ~ismember(r(ii,2),all_Seg_ind)
                                app.iseg=r(ii,2);
                            else
                                app.iseg=0;
                            end
                            if (app.ichan~=0&&app.iseg~=0)
                                highlight(app)
                            end
                        end
                    end
                end
                %% if EOG/REM
                app.temp_high=2;
                for ii=1:length(app.Evar_name_other)
                    hold((eval(app.Evar_name_other{ii})), 'on');
                end
                [c1]=find(app.temp_tab'==2);
                [r1(:,1),r1(:,2)]=find(app.temp_tab==2);
                if length(c1)==length(r1)
                    if ~isempty(c1)
                        for ii=1:length(c1)
                            if ~ismember(r1(ii,1),all_Ch_ind)
                                app.ichan=r1(ii,1);
                            else
                                app.ichan=0;
                            end
                            if ~ismember(r1(ii,2),all_Seg_ind)
                                app.iseg=r1(ii,2);
                            else
                                app.iseg=0;
                            end
                            if (app.ichan~=0&&app.iseg~=0)
                                highlight_REM(app)
                            end
                        end
                    end
                end
                %
                app.temp_high=3;
                [c2]=find(app.temp_tab'==3);
                [r2(:,1),r2(:,2)]=find(app.temp_tab==3);
                if length(c2)==length(r2)
                    if ~isempty(c2)
                        for ii=1:length(c2)
                            if ~ismember(r2(ii,1),all_Ch_ind)
                                app.ichan=r2(ii,1);
                            else
                                app.ichan=0;
                            end
                            if ~ismember(r2(ii,2),all_Seg_ind)
                                app.iseg=r2(ii,2);
                            else
                                app.iseg=0;
                            end
                            if (app.ichan~=0&&app.iseg~=0)
                                highlight_REM2(app)
                            end
                        end
                    end
                end
            end
            %
            cla(app.UIAxes10)
        end

        function highlight_REM2(app)
            Fss=app.Fs;
            L=app.seg_len*Fss;
            m=(1+((app.iseg-1)*L)) ;     n=(L+((app.iseg-1)*L));
            Temp=app.temp_epoch_data;
            mi=-app.mi_EOG;
            ma=app.mi_EOG;
            if app.temp_high==3
                plot((eval(app.Evar_name_other{app.ichan})),app.time_e(m:n),Temp(m:n,app.ichan),"Color",'#800080');
                ylim((eval(app.Evar_name_other{app.ichan})),[mi ma]);
            else
                plot((eval(app.Evar_name_other{app.ichan})),app.time_e(m:n),Temp(m:n,app.ichan),"Color",[0.07,0.62,1.00]);
                ylim((eval(app.Evar_name_other{app.ichan})),[mi ma]);
            end
        end


        function check_required_module(app)
            % check python environment and check yasa and other modules
            pe = pyenv;
            if pe.Version == ""
                fig = uifigure;
                fig.Position = [500 500 700 300];
                fig.WindowStyle = 'alwaysontop';
                message = ["Python not installed",...
                    "Due to this Automatic Sleep Scoring and REM detection is not available",...
                    "Check the ScoreREM documentation (or) Run the InstallPythonRequired Batch file available in the repository"];
                uialert(fig,message,"PythonInMatlab","Icon","info");
                pause(3);
                close(fig);
                app.python_true=0;
            else
                required_modules=["yasa","pandas","pytz","mne","numpy"];
                is_installed=zeros(length(required_modules),1);
                for iloop=1:length(required_modules)
                    try
                        % Try to import the module
                        py.importlib.import_module(required_modules(iloop));
                        is_installed(iloop) = 1;
                    catch
                        % If an error occurs, the module is not installed
                        is_installed(iloop) = 0;
                    end
                end

                if all(is_installed==1)
                    fig = uifigure;
                    fig.Position = [500 500 700 300];
                    fig.WindowStyle = 'alwaysontop';
                    message = ["Python "+pe.Version+" installed",...
                        "The required packages are also installed",...
                        "The app can perform Automatic Sleep Scoring and REM detection on EDFs with atleast one EEG channel and two EOG channel"];
                    uialert(fig,message,"PythonInMatlab","Icon","info");
                    pause(3);
                    close(fig);
                    app.python_true=1;
                    app.auto_start_time=[];
                else
                    fig = uifigure;
                    fig.Position = [500 500 700 300];
                    fig.WindowStyle = 'alwaysontop';
                    message = ["Python "+pe.Version+" installed",...
                        "Some of the required packages are not installed",...
                        "Due to this Automatic Sleep Scoring and REM detection is not available",...
                        "Check the ScoreREM documentation (or) Run the InstallPythonRequired Batch file available in the repository"];
                    uialert(fig,message,"PythonInMatlab","Icon","info");
                    pause(3);
                    close(fig);
                    app.python_true=0;
                end
            end
        end

        function YasaSleepPredict(app)
            % Matlab version 2022 or above
            % python 3.11
            % yasa, pandas, pytz and mne package installed
            % Import Python modules necessary
            % MNE
            mne_io = py.importlib.import_module("mne.io");
            read_raw_edf_func = py.getattr(mne_io, "read_raw_edf");
            yasa=py.importlib.import_module("yasa");
            pd=py.importlib.import_module("pandas");
            pytz=py.importlib.import_module("pytz");
            % Import the EDF and Truncate if necessary
            % Load an EDF file using MNE
            raw=read_raw_edf_func(py.str(app.edf_full), pyargs('preload', true));
            meas_date = raw.info{'meas_date'};
            sfreq = raw.info{'sfreq'};
            num_samples = double(py.len(raw.times));
            % Calculate the duration in seconds
            duration_seconds = num_samples / sfreq;
            % Calculate the end time
            end_time = meas_date + seconds(duration_seconds);
            % Check if a starttime is given
            if ~isempty(app.auto_start_time)
                try
                start=pytz.utc.localize(app.auto_start_time);
                start = start.replace(tzinfo=meas_date.tzinfo);
                catch
                    start=app.auto_start_time;
                    start.TimeZone=meas_date.TimeZone;
                end
                start_seconds = (start-meas_date);
                if start_seconds~=0
                raw = raw.copy().crop(tmin=py.int(seconds(start_seconds)));
                end
                start_time = start;
            else
                start_time=meas_date;
            end
            channels=app.YASA_Autoscorer_channels;
            channels(ismember(channels," "))=[];
            % Initialize the sleep staging instance
            switch length(channels)
                case 2
                    sls = yasa.SleepStaging(raw, eeg_name=py.str(channels(2)), eog_name=py.str(channels(1)));
                case 3
                    sls = yasa.SleepStaging(raw, eeg_name=py.str(channels(2)), eog_name=py.str(channels(1)),emg_name=py.str(channels(3)));
            end
            % Get the predicted sleep stages
            hypno = df2t(app,pd.Series(sls.predict()));
            % get the time vector
            Time_hypno=(start_time:seconds(30):end_time-seconds(30))';
            % hynonum
            keySet= (["N3","N2","N1","R","W","A"]);
            valueSet= [1 2 3 4 5 6];
            M = containers.Map(keySet,valueSet);
            hypnonum=double(string(arrayfun(@(x)(M(x)),hypno{:,1},'UniformOutput',false)));
            % map thehypnogram to numbers
            % Create a hypnogram table with epoch intervals
            Duration=30*ones(length(Time_hypno),1);
            onset=0:30:(30*(length(Time_hypno)-1));
            app.auto_hypno=table(Time_hypno,onset',hypno{:,1},Duration,hypnonum,'VariableNames',["Time","onset","Hypnogram","Duration","Hypnogram_number"]);
            writetable(app.auto_hypno(:,1:end-1),fullfile(app.path,erase(app.FileName,".edf")+"_Auto_Hypnogram.csv"));
            writematrix(hypno{:,1},fullfile(app.path,erase(app.FileName,".edf")+"_Auto_Hypnogram.txt"));
            app.Epoch_time=Time_hypno;
            app.Hypnogram=hypnonum;
        end

        function Get_channel_names(app,~,t)
            button_handle = findobj(t, 'Type', 'UIButton', '-depth', 1);
            button_handle.Enable='off';
            dropdown_handle = findobj(t, 'Type', 'UIDropdown', '-depth', 1);
            app.selected_channel_names=flipud(string({(dropdown_handle.Value)})');
            close(t);
            % Change the labels already there
            app.LOCLabel.Text=app.selected_channel_names(1);
            app.ROCLabel.Text=app.selected_channel_names(2);
            app.F4M1Label.Text=app.selected_channel_names(3);
            app.C4M1Label.Text=app.selected_channel_names(4);
            app.O2M1Label.Text=app.selected_channel_names(5);
            app.F3M2Label.Text=app.selected_channel_names(6);
            app.C3M2Label.Text=app.selected_channel_names(7);
            app.O1M2Label.Text=app.selected_channel_names(8);
            app.EMGLabel.Text=app.selected_channel_names(9);

        end

        function Get_YASA_Autoscorer_channels(app,t2)
            dropdown_handle = findobj(t2, 'Type', 'UIDropdown', '-depth', 1);
            app.YASA_Autoscorer_channels=flipud(string({(dropdown_handle.Value)})');
            % if EOG is not selected ask them to choose it
            close(t2);
            if isempty(app.YASA_Autoscorer_channels(1))||isempty(app.YASA_Autoscorer_channels(2))
                fig = uifigure;
                fig.Position = [500 500 700 500];
                fig.WindowStyle = 'alwaysontop';
                message = "To perform Autoscoring you need to choose one EOG and one EEG channel atleast. Please reload the EDF and select.";
                uialert(fig,message,"AutoScorerError","Icon","info");
                pause(3);
                close(fig);
                uiwait(fig);
            else
                fig = uifigure;
                fig.Position = [500 500 700 200];
                d = uiprogressdlg(fig,'Title','Computing Hypnogram using YASA package',...
                    'Indeterminate','on');
                drawnow
                d.Message ="Formatting the data and estimating the sleep states";
                % Call the scorer
                YasaSleepPredict(app);
                d.Message ="Automatic Hypnogram computed successfully";

                close(fig)
            end
        end

        function Get_Marker(app)
            try
                Marker=textscan(fopen(app.Mark_full), '%s %s', 'Delimiter', ';');
                %% Marker - Light on and Light off
                Marker_Time=Marker{1};
                Marker_Time = strrep(Marker_Time,'.','/');
                RECSTART=datetime(Marker_Time{contains(Marker{2},'Start','IgnoreCase',true)},'InputFormat','dd/MM/yyyy HH:mm:ss,SSS');
                if RECSTART<app.edf_start_time
                    app.Marker_error=1;
                    f = uifigure;
                    selection1=uiconfirm(f,'The Start marker cannot occur before edf starttime','Error','Icon','warning');
                    if strcmp(selection1,'OK')||strcmp(selection1,'Close')
                        close(f);
                    end
                    return;
                else
                RECEND=app.edf_end_time;
                app.Epoch_time=(RECSTART:seconds(30):app.edf_end_time-seconds(30))';
                app.Var_tab= table(RECSTART,RECEND);
                a1=contains(Marker{2},'Lights Off','IgnoreCase',true);
                b1=contains(Marker{2},'Light Off','IgnoreCase',true);
                if any(a1)
                    app.Var_tab.LIGHTOFF=datetime((Marker_Time{contains(Marker{2},'Lights Off','IgnoreCase',true)}),'InputFormat','dd/MM/yyyy HH:mm:ss,SSS');
                elseif any(b1)
                    app.Var_tab.LIGHTOFF=datetime((Marker_Time{contains(Marker{2},'Light Off','IgnoreCase',true)}),'InputFormat','dd/MM/yyyy HH:mm:ss,SSS');
                end
                a=contains(Marker{2},'Light On','IgnoreCase',true);
                b=contains(Marker{2},'Lights On','IgnoreCase',true);
                if any(a)
                    app.Var_tab.LIGHTON=datetime(Marker_Time{contains(Marker{2},'Light On','IgnoreCase',true)},'InputFormat','dd/MM/yyyy HH:mm:ss,SSS');
                elseif any(b)
                    app.Var_tab.LIGHTON=datetime(Marker_Time{contains(Marker{2},'Lights On','IgnoreCase',true)},'InputFormat','dd/MM/yyyy HH:mm:ss,SSS');
                end
                app.ismarker=1;
                end
            catch
                app.Marker_error=1;
                f = uifigure;
                selection1=uiconfirm(f,'The Marker file is not formatted correctly please check the repository for the Marker template or refer documentation. Please fix the Marker and reload the files','Error','Icon','warning');
                if strcmp(selection1,'OK')||strcmp(selection1,'Close')
                    close(f);
                end
                return;
            end
        end

        function Get_Hypnogram_txt(app)
            try
                File=string(readcell(app.Hyp_full,'ExpectedNumVariables',4));
                File = rmmissing(File,2);
                % Get unique and ask the user to choose the mapping
                Get_Hypno_map(app,unique(File))
                keySet = app.selected_hypnomap;
                valueSet = ["N1";"N2";"N3";"N3";"R";"W";"A";"A"];
                M = containers.Map(keySet,valueSet);
                Sleep_Stages=string(arrayfun(@(x)(M(x)),(File),'UniformOutput',false));
                keySet = ["N1";"N2";"N3";"R";"W";"A"];
                valueSet = [3 2 1 4 5 6];
                M = containers.Map(keySet,valueSet);
                label=cell2mat(arrayfun(@(x)(M(x)),(Sleep_Stages),'UniformOutput',false));
                if length(app.Epoch_time)>length(label)
                    app.Hypnogram=[label;repelem(5,length(app.Epoch_time)-length(label),1)];
                else
                    app.Hypnogram=label(1:length(app.Epoch_time));
                end
            catch
                app.Hypnogram_error=1;
                f = uifigure;
                f.Position = [500 500 700 200];
                selection1=uiconfirm(f,'The Hypnogram file is not formatted correctly. Please check the repository for the .txt Hynogram template or refer documentation. Please fix the Marker and reload the files','Error','Icon','warning');
                if strcmp(selection1,'OK')||strcmp(selection1,'Close')
                    close(f);
                end
                return;
            end
        end

        function Get_Hypno_map(app,available_hypno_labels)
            t = uifigure('Name','Choose the channels to View');
            t.Position = [500 400 450 400];
            t.WindowStyle = 'alwaysontop';
            % Hypno map
            st=100;
            uilabel(t, 'Position', [st, 290, 100, 22], 'Text', 'N1', 'FontWeight', 'bold', 'FontSize', 14);
            uidropdown(t,"Position",[200, 290, 100, 22],"Items",(available_hypno_labels)');
            uilabel(t, 'Position', [st, 260, 100, 22], 'Text', 'N2', 'FontWeight', 'bold', 'FontSize', 14);
            uidropdown(t,"Position",[200, 260, 100, 22],"Items",(available_hypno_labels)');
            uilabel(t, 'Position', [st, 230, 100, 22], 'Text', 'N3', 'FontWeight', 'bold', 'FontSize', 14);
            uidropdown(t,"Position",[200, 230, 100, 22],"Items",(available_hypno_labels)');
            uilabel(t, 'Position', [st, 200, 100, 22], 'Text', 'N3', 'FontWeight', 'bold', 'FontSize', 14);
            uidropdown(t,"Position",[200, 200, 100, 22],"Items",[{' '},(available_hypno_labels)']);
            uilabel(t, 'Position', [st, 170, 100, 22], 'Text', 'REM', 'FontWeight', 'bold', 'FontSize', 14);
            uidropdown(t,"Position",[200, 170, 100, 22],"Items",(available_hypno_labels)');
            uilabel(t, 'Position', [st, 140, 100, 22], 'Text', 'Wake', 'FontWeight', 'bold', 'FontSize', 14);
            uidropdown(t,"Position",[200, 140, 100, 22],"Items",(available_hypno_labels)');
            uilabel(t, 'Position', [st, 110, 100, 22], 'Text', 'Artefact/A', 'FontWeight', 'bold', 'FontSize', 14);
            uidropdown(t,"Position",[200, 110, 100, 22],"Items",[{' '},(available_hypno_labels)']);
            uilabel(t, 'Position', [st, 80, 100, 22], 'Text', 'Artefact/A', 'FontWeight', 'bold', 'FontSize', 14);
            uidropdown(t,"Position",[200, 80, 100, 22],"Items",[{' '},(available_hypno_labels)']);
            btn=uibutton(t,'push','Position',[200, 50, 100, 22],'FontWeight','bold','Text','Submit','ButtonPushedFcn', @(btn,event) Allot_Hypno_map(app,btn,t));
            uilabel(t, 'Position', [100, 30, 350, 22], 'Text', '* Use the two N3 to map R&K hypnograms', 'FontAngle','italic', 'FontSize', 12);

            uiwait(t)
        end

        function Allot_Hypno_map(app,~,t)
            dropdown_handle = findobj(t, 'Type', 'UIDropdown', '-depth', 1);
            app.selected_hypnomap=flipud(string({(dropdown_handle.Value)})');
            close(t);
        end

        function Get_Hypnogram_edf(app)
            try
                info=edfinfo(app.Hyp_full);
                % check if the hypnogram edf start time is the same as
                start_data=string(strcat(info.StartDate,{' '},info.StartTime));
                hypnostart_start_time=datetime(start_data,'InputFormat','dd.MM.yy HH.mm.ss');
                if app.edf_start_time~=hypnostart_start_time
                    % error message
                    app.Hypnogram_error=1;
                    f = uifigure;
                    selection1=uiconfirm(f,'Start time in the data edf and hypnogram edf dont match. Please check and reload the files','Error','Icon','warning');
                    if strcmp(selection1,'OK')||strcmp(selection1,'Close')
                        close(f);
                    end
                    return;
                else
                    rep_count=seconds(info.Annotations.Duration)/30;
                    File=repelem((info.Annotations.Annotations),rep_count);
                    Get_Hypno_map(app,unique(File))
                    keySet = app.selected_hypnomap;
                    valueSet = ["N1";"N2";"N3";"N3";"R";"W";"A";"A"];
                    M = containers.Map(keySet,valueSet);
                    Sleep_Stages=string(arrayfun(@(x)(M(x)),(File),'UniformOutput',false));
                    keySet = ["N1";"N2";"N3";"R";"W";"A"];
                    valueSet = [3 2 1 4 5 6];
                    M = containers.Map(keySet,valueSet);
                    label=cell2mat(arrayfun(@(x)(M(x)),(Sleep_Stages),'UniformOutput',false));
                    if length(app.Epoch_time)>length(label)
                    app.Hypnogram=[label;repelem(5,length(app.Epoch_time)-length(label),1)];
                    else
                    app.Hypnogram=label(1:length(app.Epoch_time));
                    end
                end
            catch
                app.Hypnogram_error=1;
                f = uifigure;
                selection1=uiconfirm(f,'The Hypnogram file is not of correct format. Please refer documentation. Please fix the Marker edf and reload the files','Error','Icon','warning');
                if strcmp(selection1,'OK')||strcmp(selection1,'Close')
                    close(f);
                end
                return;
            end
        end

        function CheckSelectedChannels(app)
            % Check the selected Channels and informs the USER the channels
            % required
            Missing_Channel_id=find(ismember(app.selected_channel_names," "));
            if any(ismember(Missing_Channel_id,1))||any(ismember(Missing_Channel_id,2))||any(ismember(Missing_Channel_id,3))||any(ismember(Missing_Channel_id,length(app.selected_channel_names)))
                app.isSelectedChannelsOK=0;
                f = uifigure;
                selection1=uiconfirm(f,'The user has to input the following channels (minimum input) to proceed: LOC, ROC, EEG1 and EMG. One or More are missing. Please reperform the data load and assign these required channels','Error','Icon','warning');
                if strcmp(selection1,'OK')||strcmp(selection1,'Close')
                    close(f);
                end
                return;

            else
                app.isSelectedChannelsOK=1;
            end

        end

        function Set_filter_settings(app,t2)
            % Get the filter values and assign the filter coefficient
            % values
            try
                order=2;
                % Power line
                dropdown_handle = findobj(t2, 'Type', 'UIDropdown', '-depth', 1);
                PowerHz=double(flipud(string({(dropdown_handle.Value)})'));
                % Filters
                Editfield_handle = findobj(t2, 'Type', 'uinumericeditfield', '-depth', 1);
                FilterHz=double(flipud(string({(Editfield_handle.Value)})'));
                % EOG filter
                Lp=(FilterHz(1)/(app.Fs_EOG/2)); Hp=(FilterHz(2)/(app.Fs_EOG/2));
                [b,a] = butter(order,[Lp Hp],'bandpass');
                app.FC_EOG.a=a; app.FC_EOG.b=b;
                % Powerline
                Lp=((PowerHz-1)/(app.Fs_EOG/2)); Hp=((PowerHz+1)/(app.Fs_EOG/2));
                [b,a] = butter(order,[Lp Hp],'stop');
                app.FC_Power.EOG.a=a; app.FC_Power.EOG.b=b;
                % EEG filter
                Lp=(FilterHz(3)/(app.Fs_EEG/2)); Hp=(FilterHz(4)/(app.Fs_EEG/2));
                [b,a] = butter(order,[Lp Hp],'bandpass');
                app.FC_EEG.a=a; app.FC_EEG.b=b;
                % Powerline
                Lp=((PowerHz-1)/(app.Fs_EEG/2)); Hp=((PowerHz+1)/(app.Fs_EEG/2));
                [b,a] = butter(order,[Lp Hp],'stop');
                app.FC_Power.EEG.a=a; app.FC_Power.EEG.b=b;
                % EMG filter
                Lp=(FilterHz(5)/(app.Fs_EMG/2)); Hp=(FilterHz(6)/(app.Fs_EMG/2));
                [b,a] = butter(order,[Lp Hp],'bandpass');
                app.FC_EMG.a=a; app.FC_EMG.b=b;
                % Powerline
                Lp=((PowerHz-1)/(app.Fs_EMG/2)); Hp=((PowerHz+1)/(app.Fs_EMG/2));
                [b,a] = butter(order,[Lp Hp],'stop');
                app.FC_Power.EMG.a=a; app.FC_Power.EMG.b=b;

                close(t2)
            catch
                f = uifigure;
                selection1=uiconfirm(f,'Filter values entered have error in them. Please check the entered range with the Sampling frequency of the signals and resubmit values that fit','Error','Icon','warning');
                if strcmp(selection1,'OK')||strcmp(selection1,'Close')
                    close(f);
                end
                return;
            end

        end

        function Out=YasaREMDetect(app,EOG,Fs)
            %% YasaREMDetect - Yasa for Matlab Rem detector released as a part of ScoreREM
            % "ScoreREM Copyright (C) 2024  Kiran K G Ravindran.
            % This program is free software: you can redistribute it and/or modify it
            % under the terms of the GNU General Public License as published by the
            % Free Software Foundation, either version 3 of the License,
            % or (at your option) any later version. This program is distributed
            % WITHOUT ANY WARRANTY.  See the GNU General Public License for more details
            % (https://www.gnu.org/licenses/)
            %
            %
            % EOG should be a 2*N matrix and N = 30* Fs. Epoch length=30s fixed
            %       Column 1 is loc and Column 2 is roc
            %       Fs - Sampling frequency
            %       EOG and Fs are mandatory
            % Default parameters if left empty
            %       amplitude = [30, 325] % 30 mV to 350 mV
            %       duration = [0.1, 3] % 100ms to 3s
            %       frequency = [0.5, 3] % 0.5 to 3 Hz
            %% 2.Prerequisites:
            % Matlab version 2022 or above
            % python 3.11
            % yasa package installed

            % Import Python modules necessary
            try
                yasa=py.importlib.import_module('yasa');
            catch
                error('yasa not installed or some other python problem')
            end
            np=py.importlib.import_module('numpy');

            % Check if all variables are available else assign data
            % Sampling Frequency
            sf=py.int(Fs);
            % Amplitude
            amp=py.list([30,325]);
            % Duration
            dur=py.list([0.1,3]);
            % Frequency
            freq=py.list([0.5,3]);
            % EOG data formatting
            loc=np.array((EOG(1,:)));
            roc=np.array((EOG(2,:)));
            % Query the events
            A=yasa.rem_detect(loc, roc, sf, hypno=py.None, amplitude=amp, duration=dur, freq_rem=freq, remove_outliers= py.bool(true), verbose=py.bool(false));
            % not empty convert to table and send back the output
            try
                Out=df2t(app,A.summary);
            catch ME
                if contains(ME.message,'py.NoneType')
                    Out=[];
                elseif contains(ME.message,'The VariableNames property must contain one name for each variable in the table.')
                    Out=[];
                else
                    error('Yasa not detected')
                end
            end
        end

        function Get_YASA_events(app,f_lab)
            %% Get EOG data and estimate the YASA labels - Requires the following
            d = uiprogressdlg(f_lab,'Title','Computing REM events using YASA package',...
                'Indeterminate','on');
            drawnow
            d.Message ="Formatting the data and estimating the REM events";
            Fs_yasa=app.Fs_EOG;
            %% Generate features 30* epochs* channels
            temp_EOG=squeeze(app.E_EOG(:,:,app.REM_indices));
            temp_out=zeros(2,30,length(app.REM_indices));
            %
            for jloop=1:size(temp_EOG,3)
                temp_epoch=squeeze(temp_EOG(:,:,jloop))';
                REM_labels=zeros(30,2);
                % Send the data and param to YASA
                Out=YasaREMDetect(app,temp_epoch,Fs_yasa);
                if ~isempty(Out)
                    start_sec=round(Out.Start);
                    end_sec=ceil(Out.End);
                    phasic_secs=unique(cell2mat(arrayfun(@(x) start_sec(x):end_sec(x),1:length(start_sec),'UniformOutput',false)));
                    if any(ismember(phasic_secs,2))&&~any(ismember(phasic_secs,1))
                        phasic_secs=[1,phasic_secs];
                    end
                    if any(ismember(phasic_secs,29))&&~any(ismember(phasic_secs,30))
                        phasic_secs=[phasic_secs,30];
                    end
                    if any(ismember(phasic_secs,0))
                        phasic_secs(phasic_secs==0)=[];
                    end
                    REM_labels(phasic_secs,:)=ones(length(phasic_secs),2);
                end
                temp_out(:,:,jloop)=REM_labels';
            end
            temp_out(temp_out==0)=3;
            temp_out(temp_out==1)=2;
            d.Message ="Automatic REM events computed successfully";
            % Convert based on the segment seconds choosen
            if app.seg_len==1
                app.Event_labels=zeros(2,30,height(app.Hypnogram));
                app.Event_labels(:,:,app.REM_indices)=temp_out;
            elseif app.seg_len>1
                app.Event_labels=zeros(2,(app.epoch_segs),height(app.Hypnogram));
                temp_out_transfer=zeros(2,(app.epoch_segs),size(temp_out,3));
                for iloop=1:size(temp_out,3)
                    temp_out_i=temp_out(:,:,iloop);
                    for jloop=1:(app.epoch_segs)
                        temp_out_transfer(:,jloop,iloop)=min(temp_out_i(:,(1+((jloop-1)*app.seg_len)):(app.seg_len+((jloop-1)*app.seg_len))),[],2);
                    end
                end
                app.Event_labels(:,:,app.REM_indices)=temp_out_transfer;
            end
            app.REM_score_label=1;
            close(f_lab)
        end
        function tab = df2t(app,df)
            % Artem Lensky (2024). PandasToMatlab (https://github.com/Lenskiy/PandasToMatlab/releases/tag/v0.23), GitHub. Retrieved May 26, 2024.
            % The function is designed to communicate between Python Pandas
            % and Matlab. Modified for use in this app
            tab = table;
            records = cell(df.values.tolist);
            if(py.isinstance(df, py.type(py.pandas.DataFrame)))
                for kloop = 1:length(records)
                    row = recordDecoding(app,records{kloop});
                    tab = [tab; row];
                end

                headerCells = df.columns.to_list;
                for kloop = 1:length(headerCells)
                    header(kloop) = headerCells(kloop).string;
                end


                % Check if there are columns with the same name
                if(length(unique(header)) == length(header))
                    tab.Properties.VariableNames = header;
                else
                    for name = unique(header)
                        sameNameCol = find(name == header);
                        if(length(sameNameCol) == 1)
                            continue;
                        end
                        tab = [tab(:,1:sameNameCol(1)-1), table(tab(:, sameNameCol).Variables, 'VariableName', name), tab(:,sameNameCol(1):end)];
                        tab(:, sameNameCol+1) = [];
                        header(sameNameCol(2:end)) = [];
                    end
                    tab.Properties.VariableNames = header;
                end

            elseif(py.isinstance(df, py.type(py.pandas.Series)))
                for l = 1:length(records)
                    [value, valType] = fieldDecoding(app,records{l});
                    row = table(value, 'VariableNames', string(df.name.char));
                    tab = [tab; row];
                end
                tab.Properties.RowNames = string(df.index.values.tolist);
            end
        end

        function row = recordDecoding(app,record)
            fields = cell(record);
            row = table;
            for l = 1:length(fields)
                [value, valType] = fieldDecoding(app,fields{l});
                tableCell = table(value, 'VariableNames', ["Var" + l]);
                row = [row, tableCell];
            end
        end

        function [value, type] = fieldDecoding(app,field)
            if(py.isinstance(field, py.type(py.bool)))
                value = logical(field);
                type = 'logical';
            elseif(py.isinstance(field, py.type(py.int)))
                value = field.int64;
                type = 'int64';
            elseif(py.isinstance(field, py.type(py.str)))
                value = string(field);
                type = 'string';
            elseif(py.isinstance(field, py.type(py.float)))
                value = double(field);
                type = 'double';
            elseif(py.isinstance(field, py.type(py.complex)))
                value = complex(field);
                type = 'complex';
            elseif(py.isinstance(field, py.type(py.list)))
                value = cell(field);
                type = 'cell';
            else
                value = field;
            end
        end
        %% Get_analysis_data
        function Data=Get_analysis_data(app,Data,Fs,len_lab,Epoch_sampl_len,start_sec)
            Dim=size(Data,2);
            start_timeseries=((start_sec-1)*Fs)+1;
            try
                Data=Data(start_timeseries:(((Epoch_sampl_len*len_lab))+start_timeseries)-1,:);
            catch
                Data=Data(start_timeseries:(((Epoch_sampl_len*len_lab))+start_timeseries)-1,:);
                Data=vertcat(Data,zeros(Fs,Dim));
            end

        end
        %% Return_true_labels
        function Return_true_labels(app,Data,start_sec,Fs)
            try
                label_length=floor(length(Data((((start_sec)*Fs)+1):end,:))/(30*Fs));
                if label_length<=length(app.Hypnogram)
                    app.Hypnogram=app.Hypnogram(1:label_length);
                else
                    app.Hypnogram=app.Hypnogram;
                end
                app.Epoch_time=app.Epoch_time(1:length(app.Hypnogram));
            catch
                error('Check start time');
            end
        end
        %% At the end when save button is pressed, estimate the sleep summary measures and REM measures
        function Get_Summary(app)
            temp_hypnogram=app.Hypnogram;
            temp_epoch_time=app.Epoch_time;


        end
        
        function displayinfo(app)
            fig1 = uifigure;
            fig1.Position = [500 500 700 550];
            fig1.WindowStyle = 'alwaysontop';
            message = ["The data is ready to be scored. Please use the Keyboard shortcuts to score.",...
                "Keyboard controls:"...
                "(right and left arrow (also b and c)- between epochs);",...
                "(i and k arrows to enable segment wise scoring);",...
                "(l and j to move between REM epochs);",...
                "[with segment enabled by pressing i - segment number ~= 0];",...
                "(a and f to move between the segments);",...
                "(q and w score segment from LOC and ROC as artefact);",...
                "(e - score the segment as Phasic REM (organe) and r- Tonic REM(purple));",...
                "[to changed the segment label from one to another you need to press the key twice];",...
                "[single press of a previously scored segment clears the label]"];
            selection1=uiconfirm(fig1,message,"Scoring Info",'Icon','info');
            if strcmp(selection1,'OK')||strcmp(selection1,'Cancel')
                close(fig1);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % "ScoreREM Copyright (C) 2024  Kiran K G Ravindran.
            % This program is free software: you can redistribute it and/or modify it
            % under the terms of the GNU General Public License as published by the
            % Free Software Foundation, either version 3 of the License,
            % or (at your option) any later version. This program is distributed
            % WITHOUT ANY WARRANTY.  See the GNU General Public License for more details
            % (https://www.gnu.org/licenses/)

            close all
            % disable all the buttons
            app.Button_7.Enable='off';
            app.Button_8.Enable='off';
            app.Button_9.Enable='off';
            app.Button_10.Enable='off';
            app.Button_6.Enable='off';
            app.Button_5.Enable='off';
            app.GOButton.Enable='off';
            app.GoToEpochDropDown.Enable="off";
            app.RETURNButton.Enable='off';
            app.OpendeletedtableButton.Enable='off';
            % Licence pop up
            fig = uifigure;
            fig.Position = [500 500 700 450];
            fig.WindowStyle = 'alwaysontop';
            message = ["ScoreREM Copyright (C) 2024  Kiran K G Ravindran",...
                "This program is free software: you can redistribute it and/or modify it under the terms of the GNU General Public License",...
                "as published by the Free Software Foundation, either version 3 of the License, or (at your option) any later version.",...
                "This program is distributed WITHOUT ANY WARRANTY.  See the GNU General Public License for more details (https://www.gnu.org/licenses/)."];
            uialert(fig,message,"License","Icon","info");
            pause(3);
            close(fig);
            drawnow;
            % initialise some of the variables and maximise
            app.ScoreREMGUIGNUGPL30UIFigure.WindowState = 'maximized';
            app.j=1;
            app.i=1;
            app.jj=0;
            app.s_en=0;
            app.ichan=0;
            app.iseg=0;
            % Check python enviroment and notify user
            check_required_module(app)
            % Reenable the buttons related loading
            app.Button_7.Enable='on';
        end

        % Button pushed function: Button_7
        function ButtonPushed(app, event)
            %% LOAD
            [edfName, edfPath] = uigetfile('*.edf', 'Select the .edf file containing the data:');
            if ~ischar(edfName)
                f = uifigure;
                selection1=uiconfirm(f,'User pressed Cancel while selecting the file containing the data','Error','Icon','warning');
                if strcmp(selection1,'OK')||strcmp(selection1,'Cancel')
                    close(f);
                end
                return;
            end
            app.FileNameEditField.Value=edfName;
            app.FileNameEditField.Enable='Off';
            app.edf_full=fullfile(edfPath,edfName);
            app.path=edfPath;
            app.FileName=edfName;
            figure(app.ScoreREMGUIGNUGPL30UIFigure);
            %% EDF edf info
            info=edfinfo(app.edf_full);
            if seconds(info.DataRecordDuration)~=1&&seconds(info.DataRecordDuration)~=30
                f = uifigure;
                selection1=uiconfirm(f,'The EDF Record Duration accepted is 1 s or 30 s. Please check and load a valid EDF','Error','Icon','warning');
                if strcmp(selection1,'OK')||strcmp(selection1,'Cancel')
                    close(f);
                end
                return;
                app.isedfOK=0;
            else
                app.isedfOK=1;
            end
            if app.isedfOK==1
                start_data=string(strcat(info.StartDate,{' '},info.StartTime));
                app.edf_start_time=datetime(start_data,'InputFormat','dd.MM.yy HH.mm.ss');
                app.edf_end_time=app.edf_start_time+(info.NumDataRecords*info.DataRecordDuration);
                app.Epoch_time=(app.edf_start_time:seconds(30):app.edf_end_time-seconds(30))';
                app.Available_channels=info.SignalLabels;
                %% Ask the user to allot the EEG, EOG and EMG channels
                t = uifigure('Name','Choose the channels to View');
                t.Position = [500 400 450 400];
                t.WindowStyle = 'alwaysontop';
                % EOG
                st=100;
                uilabel(t, 'Position', [st, 320, 100, 22], 'Text', 'LOC', 'FontWeight', 'bold', 'FontSize', 14);
                uidropdown(t,"Position",[200, 320, 100, 22],"Items",[{' '},(app.Available_channels)']);
                uilabel(t, 'Position', [st, 290, 100, 22], 'Text', 'ROC', 'FontWeight', 'bold', 'FontSize', 14);
                uidropdown(t,"Position",[200, 290, 100, 22],"Items",[{' '},(app.Available_channels)']);
                % EEG 1
                uilabel(t, 'Position', [st, 260, 100, 22], 'Text', 'EEG1', 'FontWeight', 'bold', 'FontSize', 14);
                uidropdown(t,"Position",[200, 260, 100, 22],"Items",[{' '},(app.Available_channels)']);
                uilabel(t, 'Position', [st, 230, 100, 22], 'Text', 'EEG2', 'FontWeight', 'bold', 'FontSize', 14);
                uidropdown(t,"Position",[200, 230, 100, 22],"Items",[{' '},(app.Available_channels)']);
                uilabel(t, 'Position', [st, 200, 100, 22], 'Text', 'EEG3', 'FontWeight', 'bold', 'FontSize', 14);
                uidropdown(t,"Position",[200, 200, 100, 22],"Items",[{' '},(app.Available_channels)']);
                uilabel(t, 'Position', [st, 170, 100, 22], 'Text', 'EEG4', 'FontWeight', 'bold', 'FontSize', 14);
                uidropdown(t,"Position",[200, 170, 100, 22],"Items",[{' '},(app.Available_channels)']);
                uilabel(t, 'Position', [st, 140, 100, 22], 'Text', 'EEG5', 'FontWeight', 'bold', 'FontSize', 14);
                uidropdown(t,"Position",[200, 140, 100, 22],"Items",[{' '},(app.Available_channels)']);
                uilabel(t, 'Position', [st, 110, 100, 22], 'Text', 'EEG6', 'FontWeight', 'bold', 'FontSize', 14);
                uidropdown(t,"Position",[200, 110, 100, 22],"Items",[{' '},(app.Available_channels)']);
                % EMG
                uilabel(t, 'Position', [st, 80, 100, 22], 'Text', 'EMG', 'FontWeight', 'bold', 'FontSize', 14);
                uidropdown(t,"Position",[200, 80, 100, 22],"Items",[{' '},(app.Available_channels)']);
                btn=uibutton(t,'push','Position',[200, 50, 100, 22],'FontWeight','bold','Text','Submit','ButtonPushedFcn', @(btn,event) Get_channel_names(app,btn,t));
                uiwait(t)

                CheckSelectedChannels(app)

                if app.isSelectedChannelsOK==1
                    %% Ask the user What types of files they have
                    fig = uifigure;
                    fig.Position = [500 500 700 300];
                    fig.WindowStyle = 'alwaysontop';
                    msg = ["Choose the file load options from below:","(Reference to the app documentation for more details)"];
                    title = "File load options (default- EDF Only)";
                    file_option_selection = uiconfirm(fig,msg,title, ...
                        "Options",["EDF Only","EDF + Hypnogram (.txt)","EDF + Hypnogram (.edf)","EDF + Marker (.txt)"], ...
                        "DefaultOption",1,"CancelOption",1);
                    uilabel(fig, 'Position', [100, 30, 400, 22], 'Text', '* Choose EDF + Marker (.txt) if you are using EDF + Marker (.txt) & Hypnogram', 'FontAngle','italic', 'FontSize', 12);
                    close(fig);
                    % set the marker and hypnogram error signals
                    app.Marker_error=0;
                    app.Hypnogram_error=0;
                    switch file_option_selection
                        case "EDF Only"
                            app.ismarker=0;
                            % skip the rest. Auto score PSG from the edf start if
                            % the python packages are available
                            app.Hyp_full=[];
                            app.Mark_full=[];
                            if app.python_true==1
                                t2 = uifigure('Name','Choose the channels to View');
                                t2.Position = [500 400 450 200];
                                t2.WindowStyle = 'alwaysontop';
                                % EOG
                                st=100;
                                uilabel(t2, 'Position', [st, 140, 100, 22], 'Text', 'EOG', 'FontWeight', 'bold', 'FontSize', 14);
                                uidropdown(t2,"Position",[200, 140, 100, 22],"Items",[cellstr(app.selected_channel_names(1)),(app.selected_channel_names)']);
                                uilabel(t2, 'Position', [st, 110, 100, 22], 'Text', 'EEG', 'FontWeight', 'bold', 'FontSize', 14);
                                uidropdown(t2,"Position",[200, 110, 100, 22],"Items",[cellstr(app.selected_channel_names(4)),(app.selected_channel_names)']);
                                % EMG
                                uilabel(t2, 'Position', [st, 80, 100, 22], 'Text', 'EMG', 'FontWeight', 'bold', 'FontSize', 14);
                                uidropdown(t2,"Position",[200, 80, 100, 22],"Items",[cellstr(app.selected_channel_names(end)),(app.selected_channel_names)',{' '}]);
                                btn2=uibutton(t2,'push','Position',[200, 50, 100, 22],'FontWeight','bold','Text','Submit','ButtonPushedFcn', @(btn2,event) Get_YASA_Autoscorer_channels(app,t2));
                                uiwait(t2)
                                fig = uifigure;
                                fig.Position = [500 500 700 300];
                                fig.WindowStyle = 'alwaysontop';
                                message = ["The automated hypnogram was successfully generated",...
                                    "You can find the generated hypnogram in the folder containing the loaded edf",...
                                    "Please click on the START DATA IMPORT button to proceed with scoring"];
                                uialert(fig,message,"Data Checks in Progress","Icon","info");
                                pause(3);
                                close(fig);
                            else
                                fig = uifigure;
                                fig.Position = [500 500 700 500];
                                fig.WindowStyle = 'alwaysontop';
                                message = ["Python interface unavailable.",...
                                    "The data import will proceed without hypnogram",...
                                    "Please click on the START DATA IMPORT button"];
                                uialert(fig,message,"Data Checks in Progress","Icon","info");
                                pause(3);
                                close(fig);
                            end

                        case "EDF + Hypnogram (.txt)"
                            app.ismarker=0;
                            [HypName, HypPath] = uigetfile('*.txt', 'Select the .txt file containing the Hypnogram:');
                            Ah=(contains(HypName,'Sleep profile','IgnoreCase',true)||contains(HypName,'Hypnogram','IgnoreCase',true));
                            if ~Ah
                                f11 = uifigure;
                                f11.Position = [500 500 700 200];
                                f11.WindowStyle = 'alwaysontop';
                                selection21=uiconfirm(f11,'The file containing the Hypnogram does not contain "Sleep profile" or "Hypnogram" in its name','Error','Icon','warning');
                                if strcmp(selection21,'OK')||strcmp(selection21,'Cancel')
                                    close(f11);
                                end
                                return;
                            end
                            if ~ischar(HypName)
                                f1 = uifigure;
                                f1.Position = [500 500 700 200];
                                f1.WindowStyle = 'alwaysontop';
                                selection2=uiconfirm(f1,'User pressed Cancel while selecting the file containing the hypnogram','Error','Icon','warning');
                                if strcmp(selection2,'OK')||strcmp(selection2,'Cancel')
                                    close(f1);
                                end
                                return;
                            end
                            figure(app.ScoreREMGUIGNUGPL30UIFigure);
                            app.Hyp_full=fullfile(HypPath, HypName);
                            Get_Hypnogram_txt(app)
                            fig = uifigure;
                            fig.Position = [500 500 700 300];
                            fig.WindowStyle = 'alwaysontop';
                            message = ["The hypnogram was successfully loaded",...
                                "Please click on the START DATA IMPORT button to proceed with scoring"];
                            uialert(fig,message,"Data Checks in Progress","Icon","info");
                            pause(3);
                            close(fig);
                        case "EDF + Hypnogram (.edf)"
                            app.ismarker=0;
                            [HypName, HypPath] = uigetfile('*.edf', 'Select the .edf file containing the Hypnogram as an annotation:');
                            Ah=(contains(HypName,'Sleep profile','IgnoreCase',true)||contains(HypName,'Hypnogram','IgnoreCase',true));
                            if ~Ah
                                f11 = uifigure;
                                f11.Position = [500 500 700 200];
                                f11.WindowStyle = 'alwaysontop';
                                selection21=uiconfirm(f11,'The file containing the Hypnogram does not contain "Sleep profile", or "Hypnogram" in its name','Error','Icon','warning');
                                if strcmp(selection21,'OK')||strcmp(selection21,'Cancel')
                                    close(f11);
                                end
                                return;
                            end
                            if ~ischar(HypName)
                                f1 = uifigure;
                                f1.Position = [500 500 700 200];
                                f1.WindowStyle = 'alwaysontop';
                                selection2=uiconfirm(f1,'User pressed Cancel while selecting the file containing the hypnogram','Error','Icon','warning');
                                if strcmp(selection2,'OK')||strcmp(selection2,'Cancel')
                                    close(f1);
                                end
                                return;
                            end
                            figure(app.ScoreREMGUIGNUGPL30UIFigure);
                            app.Hyp_full=fullfile(HypPath, HypName);
                            Get_Hypnogram_edf(app)
                            fig = uifigure;
                            fig.Position = [500 500 700 300];
                            fig.WindowStyle = 'alwaysontop';
                            message = ["The hypnogram was successfully loaded",...
                                "Please click on the START DATA IMPORT button to proceed with scoring"];
                            uialert(fig,message,"Data Checks in Progress","Icon","info");
                            pause(3);
                            close(fig);
                        case "EDF + Marker (.txt)"
                            [MarkName, MarkPath] = uigetfile('*.txt', 'Select the .txt file containing the Markers:');
                            Am=(contains(MarkName,'Markers','IgnoreCase',true));
                            if ~Am
                                f21 = uifigure;
                                f21.WindowStyle = 'alwaysontop';
                                selection31=uiconfirm(f21,'The file containing the Markers does not contain "Markers" in its name','Error','Icon','warning');
                                if strcmp(selection31,'OK')||strcmp(selection31,'Cancel')
                                    close(f21);
                                end
                                return;
                            end
                            figure(app.ScoreREMGUIGNUGPL30UIFigure);

                            if ~ischar(MarkName)
                                f2 = uifigure;
                                f2.Position = [500 500 700 200];
                                f2.WindowStyle = 'alwaysontop';
                                selection3=uiconfirm(f2,'User pressed Cancel while selecting the file containing the Markers','Error','Icon','warning');
                                if strcmp(selection3,'OK')||strcmp(selection3,'Cancel')
                                    close(f2);
                                end
                                return;
                            end
                            app.Mark_full=fullfile(MarkPath,MarkName);
                            % Load the marker and find the start time and set the  and other
                            % info
                            Get_Marker(app);
                            % proceed further
                            if app.Marker_error==0
                                % Check if the user has a hypnogram that starts with
                                % the marker start time.
                                fig = uifigure;
                                fig.Position = [500 500 700 500];
                                fig.WindowStyle = 'alwaysontop';
                                msg = ["Choose the Hypnogram options from below:","(Reference to the app documentation for more details)"];
                                if app.python_true==1
                                    title = "File load options (default- Auto)";
                                    Hypnogram_option_selection = uiconfirm(fig,msg,title, ...
                                        "Options",["AutoHypnogram","Hypnogram (.txt)","None"], ...
                                        "DefaultOption",1,"CancelOption",1);
                                    uilabel(fig, 'Position', [100, 30, 400, 22], 'Text', '* Choose EDF + Marker (.txt) if you are using EDF + Marker (.txt) & Hypnogram', 'FontAngle','italic', 'FontSize', 12);
                                    close(fig);
                                else
                                    title = "File load options (default- None)";
                                    Hypnogram_option_selection = uiconfirm(fig,msg,title, ...
                                        "Options",["Hypnogram (.txt)","None"], ...
                                        "DefaultOption",2,"CancelOption",2);
                                    uilabel(fig, 'Position', [100, 30, 400, 22], 'Text', '* Choose EDF + Marker (.txt) if you are using EDF + Marker (.txt) & Hypnogram', 'FontAngle','italic', 'FontSize', 12);
                                    close(fig);
                                end

                                switch Hypnogram_option_selection

                                    case "AutoHypnogram"
                                        app.Hyp_full=[];
                                        app.auto_start_time=app.Var_tab.RECSTART;
                                        t2 = uifigure('Name','Choose the channels to View');
                                        t2.Position = [500 400 450 200];
                                        t2.WindowStyle = 'alwaysontop';
                                        % EOG
                                        st=100;
                                        uilabel(t2, 'Position', [st, 140, 100, 22], 'Text', 'EOG', 'FontWeight', 'bold', 'FontSize', 14);
                                        uidropdown(t2,"Position",[200, 140, 100, 22],"Items",[cellstr(app.selected_channel_names(1)),(app.selected_channel_names)']);
                                        uilabel(t2, 'Position', [st, 110, 100, 22], 'Text', 'EEG', 'FontWeight', 'bold', 'FontSize', 14);
                                        uidropdown(t2,"Position",[200, 110, 100, 22],"Items",[cellstr(app.selected_channel_names(4)),(app.selected_channel_names)']);
                                        % EMG
                                        uilabel(t2, 'Position', [st, 80, 100, 22], 'Text', 'EMG', 'FontWeight', 'bold', 'FontSize', 14);
                                        uidropdown(t2,"Position",[200, 80, 100, 22],"Items",[cellstr(app.selected_channel_names(end)),(app.selected_channel_names)',{' '}]);
                                        btn2=uibutton(t2,'push','Position',[200, 50, 100, 22],'FontWeight','bold','Text','Submit','ButtonPushedFcn', @(btn2,event) Get_YASA_Autoscorer_channels(app,t2));
                                        uiwait(t2)
                                        fig = uifigure;
                                        fig.Position = [500 500 700 300];
                                        fig.WindowStyle = 'alwaysontop';
                                        message = ["The automated hypnogram was successfully generated",...
                                            "You can find the generated hypnogram in the folder containing the loaded edf",...
                                            "Please click on the START DATA IMPORT button to proceed with scoring"];
                                        uialert(fig,message,"Data Checks in Progress","Icon","info");
                                        pause(3);
                                        close(fig);
                                    case "None"
                                        app.Hyp_full=[];
                                        app.Mark_full=[];
                                    case "Hypnogram (.txt)"
                                        [HypName, HypPath] = uigetfile('*.txt', 'Select the .txt file containing the Hypnogram:');
                                        Ah=(contains(HypName,'Sleep profile','IgnoreCase',true)||contains(HypName,'Hypnogram','IgnoreCase',true));
                                        if ~Ah
                                            f11 = uifigure;
                                            f11.WindowStyle = 'alwaysontop';
                                            selection21=uiconfirm(f11,'The file containing the Hypnogram does not contain "Sleep Profile" or "Hypnogram" in its name','Error','Icon','warning');
                                            if strcmp(selection21,'OK')||strcmp(selection21,'Cancel')
                                                close(f11);
                                            end
                                            return;
                                        end
                                        if ~ischar(HypName)
                                            f1 = uifigure;
                                            f1.Position = [500 500 700 200];
                                            f1.WindowStyle = 'alwaysontop';
                                            selection2=uiconfirm(f1,'User pressed Cancel while selecting the file containing the hypnogram','Error','Icon','warning');
                                            if strcmp(selection2,'OK')||strcmp(selection2,'Cancel')
                                                close(f1);
                                            end
                                            return;
                                        end
                                        if app.Hypnogram_error~=1
                                            figure(app.ScoreREMGUIGNUGPL30UIFigure);
                                            app.Hyp_full=fullfile(HypPath, HypName);
                                            Get_Hypnogram_txt(app)
                                            fig = uifigure;
                                            fig.Position = [500 500 700 300];
                                            fig.WindowStyle = 'alwaysontop';
                                            message = ["The hypnogram was successfully loaded",...
                                                "Please click on the START DATA IMPORT button to proceed with scoring"];
                                            uialert(fig,message,"Data Checks in Progress","Icon","info");
                                            pause(3);
                                            close(fig);
                                        end
                                end
                            end
                    end
                    figure(app.ScoreREMGUIGNUGPL30UIFigure);
                    app.Button_8.Enable='on';
                end
            end
        end

        % Button pushed function: Button_8
        function Button_2Pushed(app, event)
            %% Start
            Epoch_len=30; %fixed value
            %  initialize the start of plotting
            if isprop(app, 'edf_full') && ~isempty(app.edf_full)
                figure(app.ScoreREMGUIGNUGPL30UIFigure);
                cla(app.UIAxes10)
                app.mi_EEG=app.EEGuVEditField.Value;
                app.mi_EOG=app.EOGuVEditField.Value;
                app.mi_EMG=app.EMGuVEditField.Value;
                app.EEGuVEditField.Enable='off';
                app.EOGuVEditField.Enable='off';
                app.EMGuVEditField.Enable='off';
                %% Get the users input on the REM scoring
                list = {'1','2','3'};
                [indx,~] = listdlg('PromptString',{'Choose the segment length'},'ListString',list,'SelectionMode','single');
                app.seg_len=indx;
                %% Load data based on all the possible scenarios
                % Check the exisiting variables
                % app.Hypnogram app.Epoch_time
                % selected_channel_names; Marker_error; Hypnogram_error;selected_hypnomap
                % edf_start_time;edf_end_time
                %
                % Check if the Epochtime, HYpnogram match

                %% Get the filter setting
                channels_import=app.selected_channel_names;
                channels_import(ismember(channels_import," "))=[];
                Info=edfinfo(app.edf_full);
                
                % Check for prefilter
                % EEG
                Data=edfread(app.edf_full,'SelectedSignals',cellstr(unique(channels_import,'stable')),'TimeOutputType','datetime');
                if length(unique(channels_import,'stable'))<length(channels_import)
                    eog_duplicate=1;
                else
                    eog_duplicate=0;
                end
                time_temp=Data.("Record Time");
                sec_num=seconds(time_temp(2)-time_temp(1));
                Variab_names=string(Data.Properties.VariableNames);
                app.Fs_EEG=length(Data.(Variab_names(end-1)){1})/sec_num; 
                app.Fs_EOG=length(Data.(Variab_names(1)){1})/sec_num;
                app.Fs_EMG=length(Data.(Variab_names(end)){1})/sec_num;

                if app.Fs_EEG==app.Fs_EOG
                    try
                        temp_EEG_pf=Info.Prefilter(Info.SignalLabels==channels_import(3));
                        if ~ismember(temp_EEG_pf,"")
                            EEGLP=double(extractBetween(temp_EEG_pf,"LP:","Hz"));
                            EEGHP=double(extractBetween(temp_EEG_pf,"HP:","Hz"));
                        else
                            EEGLP=(app.Fs_EEG/2)-1;
                            EEGHP=0.3;
                        end
                        if contains(temp_EEG_pf,"cassette")||app.Fs_EMG==1
                            isfilter=0;
                        else
                            isfilter=1;
                        end
                    catch
                        EEGLP=(app.Fs_EEG/2)-1;
                        EEGHP=0.3;
                    end
                    if isfilter==1
                        % EOG
                        try
                            temp_EOG_pf=Info.Prefilter(Info.SignalLabels==channels_import(1));
                            if ~ismember(temp_EOG_pf,"")
                                EOGLP=double(extractBetween(temp_EOG_pf,"LP:","Hz"));
                                EOGHP=double(extractBetween(temp_EOG_pf,"HP:","Hz"));
                            else
                                EOGLP=(app.Fs_EOG/2)-1;
                                EOGHP=0.3;
                            end
                        catch
                            EOGLP=(app.Fs_EOG/2)-1;
                            EOGHP=0.3;
                        end
                        % EMG
                        try
                            temp_EMG_pf=Info.Prefilter(Info.SignalLabels==channels_import(end));
                            if ~ismember(temp_EMG_pf,"")
                                EMGLP=double(extractBetween(temp_EMG_pf,"LP:","Hz"));
                                EMGHP=double(extractBetween(temp_EMG_pf,"HP:","Hz"));
                            else
                                EMGLP=(app.Fs_EMG/2)-1;
                                EMGHP=0.3;
                            end
                        catch
                            EMGLP=(app.Fs_EMG/2)-1;
                            EMGHP=0.3;
                        end

                        t2 = uifigure('Name','Choose the filter setting (Filter order-2)');
                        t2.Position = [500 500 450 200];
                        t2.WindowStyle = 'alwaysontop';
                        % EOG
                        st=100;
                        uilabel(t2, 'Position', [st, 140, 100, 22], 'Text', 'EOG: HP & LP (Hz)', 'FontWeight', 'bold', 'FontSize', 14);
                        uieditfield(t2,"numeric","Position",[200, 140, 80, 22],"Limits",[EOGHP EOGLP],'Value',0.3);
                        if EOGLP>35
                            uieditfield(t2,"numeric","Position",[300, 140, 80, 22],"Limits",[EOGHP+0.1 EOGLP],'Value',35);
                        else
                            uieditfield(t2,"numeric","Position",[300, 140, 80, 22],"Limits",[EOGHP+0.1 EOGLP],'Value',EOGLP);
                        end
                        %EEG
                        uilabel(t2, 'Position', [st, 110, 100, 22], 'Text', 'EEG: HP & LP (Hz)', 'FontWeight', 'bold', 'FontSize', 14);
                        uieditfield(t2,"numeric","Position",[200, 110, 80, 22],"Limits",[EEGHP EEGLP],'Value',0.3);
                        if EEGLP>35
                            uieditfield(t2,"numeric","Position",[300, 110, 80, 22],"Limits",[EEGHP+0.1 EEGLP],'Value',35);
                        else
                            uieditfield(t2,"numeric","Position",[300, 110, 80, 22],"Limits",[EEGHP+0.1 EEGLP],'Value',EEGLP);
                        end
                        % EMG
                        uilabel(t2, 'Position', [st, 80, 100, 22], 'Text', 'EMG: HP & LP (Hz)', 'FontWeight', 'bold', 'FontSize', 14);
                        uieditfield(t2,"numeric","Position",[200, 80, 80, 22],"Limits",[EMGHP EMGLP],'Value',10);
                        if EMGLP>100
                            uieditfield(t2,"numeric","Position",[300, 80, 80, 22],"Limits",[EMGHP+0.1 EMGLP],'Value',100);
                        else
                            uieditfield(t2,"numeric","Position",[300, 80, 80, 22],"Limits",[EMGHP+0.1 EMGLP],'Value',EMGLP);
                        end
                        %
                        uilabel(t2,'Position', [st, 50, 100, 22], 'Text', 'PowerLine (Hz)', 'FontWeight', 'bold', 'FontSize', 14);
                        uidropdown(t2,"Position",[200, 50, 80, 22],"Items",["50","60"]);
                        %
                        btn2=uibutton(t2,'push','Position',[200, 20, 80, 22],'FontWeight','bold','Text','Submit','ButtonPushedFcn', @(btn2,event) Set_filter_settings(app,t2));
                        uiwait(t2)
                    else
                        fig = uifigure;
                        fig.Position = [500 500 700 200];
                        fig.WindowStyle = 'alwaysontop';
                        message = "Filtering unavailable since the recording is a enhanced casetter recording";
                        uialert(fig,message,"Data Checks in Progress","Icon","info");
                        pause(3);
                        close(fig);
                    end
                    %%
                    if app.ismarker==0
                        
                        
                        %% If a Marker file is absent
                        app.LightsoffEditField.Value="Info Unavailable";
                        app.LightsoffEditField.Enable='Off';
                        app.LightsonEditField.Value="Info Unavailable";
                        app.LightsonEditField.Enable='Off';
                        if seconds(Info.DataRecordDuration)==1
                            Epoch_sampl_len=30;
                        elseif seconds(Info.DataRecordDuration)==30
                            Epoch_sampl_len=1;
                        end
                        %% initialize the variables
                        Epoch=zeros(30*app.Fs_EEG,6,length(app.Epoch_time));
                        Epoch_EOG=zeros(30*app.Fs_EOG,2,length(app.Epoch_time));
                        Epoch_EMG=zeros(30*app.Fs_EMG,1,length(app.Epoch_time));
                        seg_num=Epoch_len/app.seg_len;
                        Seg_len=(app.seg_len*app.Fs_EOG);
                        Data_segment_all=zeros(Seg_len,2,length(app.Epoch_time)*seg_num);
                        for iloop=1:length(app.Epoch_time)
                            % Get epoch data
                            m=(1+((iloop-1)*Epoch_sampl_len)) ;
                            n=(Epoch_sampl_len+((iloop-1)*Epoch_sampl_len));
                            % Colapse the data from the table,filter and assign
                            temp_data=Data(m:n,:);
                            channels_temp=string(temp_data.Properties.VariableNames);
                            if isfilter==1
                                Epoch_EMG(:,iloop)=filtfilt(app.FC_EMG.b,app.FC_EMG.a,filtfilt(app.FC_Power.EMG.b,app.FC_Power.EMG.a,vertcat(temp_data.(channels_temp(end)){:})));
                                if eog_duplicate==1
                                    temp_EOG=[vertcat(temp_data.(channels_temp(1)){:}),-vertcat(temp_data.(channels_temp(1)){:})];
                                else
                                    temp_EOG=[vertcat(temp_data.(channels_temp(1)){:}),vertcat(temp_data.(channels_temp(2)){:})];
                                end
                                temp_EOG=filtfilt(app.FC_EOG.b,app.FC_EOG.a,filtfilt(app.FC_Power.EOG.b,app.FC_Power.EOG.a,temp_EOG));
                                Epoch_EOG(:,:,iloop)=temp_EOG;
                                for jloop=1:length(channels_temp)-3
                                    Epoch(:,jloop,iloop)=filtfilt(app.FC_EEG.b,app.FC_EEG.a,filtfilt(app.FC_Power.EEG.b,app.FC_Power.EEG.a,vertcat(temp_data.(channels_temp(jloop+2)){:})));
                                end
                            else
                                Epoch_EMG(:,iloop)=vertcat(temp_data.(channels_temp(end)){:});
                                if eog_duplicate==1
                                    temp_EOG=[vertcat(temp_data.(channels_temp(1)){:}),-vertcat(temp_data.(channels_temp(1)){:})];
                                else
                                    temp_EOG=[vertcat(temp_data.(channels_temp(1)){:}),vertcat(temp_data.(channels_temp(2)){:})];
                                end
                                Epoch_EOG(:,:,iloop)=temp_EOG;
                                for jloop=1:length(channels_temp)-3
                                    Epoch(:,jloop,iloop)=vertcat(temp_data.(channels_temp(jloop+2)){:});
                                end
                            end
                            % Data_segment_all
                            for jloop=1:seg_num
                                ms=(1+((jloop-1)*Seg_len));
                                ns=(Seg_len+((jloop-1)*Seg_len));
                                Data_segment_all(:,:,((iloop-1)*seg_num)+jloop)=temp_EOG(ms:ns,:);
                            end
                        end

                        app.Y=Data_segment_all;
                        app.E=Epoch;
                        app.E_EOG=Epoch_EOG;
                        app.E_EMG=Epoch_EMG;
                        clear Data Data_segment_all Epoch Epoch_EOG Epoch_EMG
                    else
                        app.edf_start_time.TimeZone=app.Epoch_time(1).TimeZone;
                        app.edf_end_time.TimeZone=app.Epoch_time(1).TimeZone;
                        %% If the Marker file is present
                        try
                            app.LightsoffEditField.Value=string(app.Var_tab.LIGHTOFF);
                            app.LightsoffEditField.Enable='Off';
                        catch
                            app.LightsoffEditField.Value="Info Unavailable";
                            app.LightsoffEditField.Enable='Off';
                        end
                        try
                            app.LightsonEditField.Value=string(app.Var_tab.LIGHTON);
                            app.LightsonEditField.Enable='Off';
                        catch
                            app.LightsonEditField.Value="Info Unavailable";
                            app.LightsonEditField.Enable='Off';
                        end
                        if seconds(Info.DataRecordDuration)==1
                            Epoch_sampl_len=30*app.Fs_EOG;
                            Epoch_sampl_len_EMG=30*app.Fs_EMG;
                        elseif seconds(Info.DataRecordDuration)==30
                            Epoch_sampl_len=1*app.Fs_EOG;
                            Epoch_sampl_len_EMG=1*app.Fs_EMG;
                        end
                        % Combine in data
                        channels_temp=string(Data.Properties.VariableNames);
                        Data_merged=zeros(((seconds(app.edf_end_time-app.edf_start_time))*app.Fs_EOG),length(channels_temp)-1);
                        Data_merged_EMG=zeros((seconds(app.edf_end_time-app.edf_start_time)*app.Fs_EMG),1);

                        for ijloop=1:length(channels_temp)-1
                        Data_merged(:,ijloop)=vertcat(Data.(channels_temp(ijloop)){:});
                        end
                        Data_merged_EMG=vertcat(Data.(channels_temp(end)){:});
                        start_sec=seconds(app.Epoch_time(1)-app.edf_start_time);

                        Return_true_labels(app,Data_merged,start_sec,app.Fs_EOG)

                        Data_merged=Get_analysis_data(app,Data_merged,app.Fs_EOG,length(app.Epoch_time),Epoch_sampl_len,start_sec);
                        Data_merged_EMG=Get_analysis_data(app,Data_merged_EMG,app.Fs_EMG,length(app.Epoch_time),Epoch_sampl_len_EMG,start_sec);

                        %% initialize the variables
                        Epoch=zeros(30*app.Fs_EEG,6,length(app.Epoch_time));
                        Epoch_EOG=zeros(30*app.Fs_EOG,2,length(app.Epoch_time));
                        Epoch_EMG=zeros(30*app.Fs_EMG,1,length(app.Epoch_time));
                        seg_num=Epoch_len/app.seg_len;
                        Seg_len=(app.seg_len*app.Fs_EOG);
                        Data_segment_all=zeros(Seg_len,2,length(app.Epoch_time)*seg_num);
                        for iloop=1:length(app.Epoch_time)
                            % Get epoch data
                            m=(1+((iloop-1)*Epoch_sampl_len)) ;
                            n=(Epoch_sampl_len+((iloop-1)*Epoch_sampl_len));
                            %
                            m_emg=(1+((iloop-1)*Epoch_sampl_len_EMG)) ;
                            n_emg=(Epoch_sampl_len_EMG+((iloop-1)*Epoch_sampl_len_EMG));
                            % Colapse the data from the table,filter and assign
                            if isfilter==1
                                Epoch_EMG(:,iloop)=filtfilt(app.FC_EMG.b,app.FC_EMG.a,filtfilt(app.FC_Power.EMG.b,app.FC_Power.EMG.a,Data_merged_EMG(m_emg:n_emg,:)));
                                if eog_duplicate==1
                                    temp_EOG=[Data_merged(m:n,1),-Data_merged(m:n,1)];
                                else
                                    temp_EOG=Data_merged(m:n,1:2);
                                end
                                temp_EOG=filtfilt(app.FC_EOG.b,app.FC_EOG.a,filtfilt(app.FC_Power.EOG.b,app.FC_Power.EOG.a,temp_EOG));
                                Epoch_EOG(:,:,iloop)=temp_EOG;
                                Epoch(:,1:length(channels_temp)-3,iloop)=filtfilt(app.FC_EEG.b,app.FC_EEG.a,filtfilt(app.FC_Power.EEG.b,app.FC_Power.EEG.a,Data_merged(m:n,3:end)));
                                
                            else
                                Epoch_EMG(:,iloop)=Data_merged_EMG(m_emg:n_emg,:);
                                if eog_duplicate==1
                                    temp_EOG=[Data_merged(m:n,1),-Data_merged(m:n,1)];
                                else
                                    temp_EOG=Data_merged(m:n,1:2);
                                end
                                Epoch_EOG(:,:,iloop)=temp_EOG;
                                Epoch(:,1:length(channels_temp)-3,iloop)=Data_merged(m:n,3:end);
                            end
                            % Data_segment_all
                            for jloop=1:seg_num
                                ms=(1+((jloop-1)*Seg_len));
                                ns=(Seg_len+((jloop-1)*Seg_len));
                                Data_segment_all(:,:,((iloop-1)*seg_num)+jloop)=temp_EOG(ms:ns,:);
                            end
                        end

                        app.Y=Data_segment_all;
                        app.E=Epoch;
                        app.E_EOG=Epoch_EOG;
                        app.E_EMG=Epoch_EMG;
                        clear Data Data_segment_all Epoch Epoch_EOG Epoch_EMG Data_merged Data_merged_EMG

                    end
                    if length(app.Hypnogram)>length(app.Epoch_time)
                        app.Hypnogram=app.Hypnogram(1:length(app.Epoch_time));
                    elseif length(app.Hypnogram)<length(app.Epoch_time)
                        app.Hypnogram=[app.Hypnogram;5*ones(length(app.Epoch_time)-length(app.Hypnogram),1)];
                    end
                    %% Hypnogram table
                    valueSet = ["N1";"N2";"N3";"R";"W";"A"];
                    keySet = [3 2 1 4 5 6];
                    M = containers.Map(keySet,valueSet);
                    app.Hypnogram_Str=string((arrayfun(@(x)(M(x)),app.Hypnogram,'UniformOutput',false)));
                    Go_drop_down_item=string(datetime(app.Epoch_time,"Format","HH:mm:ss"))+" ["+app.Hypnogram_Str+"]";
                    app.GoToEpochDropDown.Items=[" ";Go_drop_down_item];
                    %%
                    app.epoch_len=size(app.E,3);
                    app.end_len=size(app.Y,3);
                    app.num_chan_find=size(app.E,2);
                    app.Fs=app.Fs_EOG;
                    app.epoch_segs=30/app.seg_len;
                    %% Enable the rest of the buttons
                    app.Button_9.Enable='on';
                    app.Button_10.Enable='on';
                    app.Button_6.Enable='on';
                    app.Button_5.Enable='on';
                    app.GOButton.Enable='on';
                    app.GoToEpochDropDown.Enable="on";
                    app.OpendeletedtableButton.Enable='on';
                    %%
                    figure(app.ScoreREMGUIGNUGPL30UIFigure);
                    %%
                    % REM indices
                    app.REM_indices=find(app.Hypnogram==4);
                    app.REM_i=0;
                    %% Autoscore REM
                    % if eog_duplicate==0
                        f_lab = uifigure;
                        f_lab.Position = [500 500 700 200];
                        f_lab.WindowStyle = 'alwaysontop';
                        msg1 = 'Autoscore EOG REM Events?';
                        selection_lab = uiconfirm(f_lab,msg1,'Create Phasic REM labels','Options',{'Yes','No'},'DefaultOption',1);
                        app.REM_score_label=0;
                        if strcmp(selection_lab,'Yes')
                            app.REM_score_label=1;
                            Get_YASA_events(app,f_lab)
                        end
                    % else
                    %     fig = uifigure;
                    %     fig.Position = [500 500 700 200];
                    %     fig.WindowStyle = 'alwaysontop';
                    %     message = "Autoscoring EOG REM is not possible since the EOG channels choosen are the same. Both LOC and ROC are required.";
                    %     uialert(fig,message,"Create Phasic REM labels","Icon","info");
                    %     pause(3);
                    %     close(fig);
                    % end
                    %%
                    f_lab = uifigure;
                    f_lab.Position = [500 500 700 200];
                    f_lab.WindowStyle = 'alwaysontop';
                    msg1 = 'Choose whether Epochs scored as Wake are to be considered as ARTEFACTS';
                    selection_lab = uiconfirm(f_lab,msg1,'Create labels','Options',{'Artefact','Not Artefact'},'DefaultOption',2);
                    app.W_label=0;
                    if strcmp(selection_lab,'Artefact')
                        app.W_label=1;
                    end
                    close(f_lab);
                    % Plotting and esthetics
                    % Creating the timeseries for the N s segment and 30 s epoch
                    app.time=1/(app.Fs):1/(app.Fs):app.seg_len;
                    app.time_e=1/(app.Fs):1/(app.Fs):30;
                    % finding the indices where the vertical markers should go
                    tt_time=app.time_e;
                    app.time_em=1/(app.Fs_EMG):1/(app.Fs_EMG):30;
                    app.vertlines=app.seg_len:app.seg_len:30;
                    app.vertlines(end)=[];
                    ind_vert=zeros(length(app.vertlines),1);
                    for ii=1:length(app.vertlines)
                        [~,ind_vert(ii)] = min(abs(tt_time-app.vertlines(ii)));
                    end
                    app.vert_ind=ind_vert;
                    % Create the instance of the whole epoch labels
                    tt=array2table(strings(2,app.epoch_segs));
                    tt.Properties.RowNames={'EOG-L','EOG-R'};
                    app.Epoch_labels=tt;
                    ft=uifigure;
                    ft.Position=[20 20 600 250];
                    app.tab = uitable(ft,'Data',app.Epoch_labels,'ColumnWidth',{50 50 50 50 50 50 50 50 50 50});
                    tab_t=app.tab;
                    tab_t.RowName={'EOG-L','EOG-R'};
                    tab_t.ColumnName=cellstr(string(1:1:app.epoch_segs));
                    tab_t.Position=[20 20 555 200];
                    % Get the table dimensions
                    app.Row_num=2;
                    app.Column_num=app.epoch_segs;
                    %
                    app.Evar_name={'app.UIAxes5_7','app.UIAxes5_6','app.UIAxes5_5','app.UIAxes5_4','app.UIAxes5_3','app.UIAxes5_2'};
                    app.Evar_name_other={'app.UIAxes5_9','app.UIAxes5_8','app.UIAxes5'};
                    %
                    for ii=1:length(app.Evar_name)
                        hold((eval(app.Evar_name{ii})), 'off');
                    end
                    for ii=1:length(app.Evar_name_other)
                        hold((eval(app.Evar_name_other{ii})), 'off');
                    end
                    %
                    update_seg(app)
                    if app.W_label==1
                        threshold_labels=zeros(app.Row_num,app.Column_num,app.epoch_len);
                        for jjj=1:size(threshold_labels,3)
                            if app.Hypnogram(jjj)==5||app.Hypnogram(jjj)==6
                                threshold_labels(:,:,jjj)=ones(size(threshold_labels,1),size(threshold_labels,2));
                            end
                        end
                        app.Label_scored=threshold_labels;
                        if app.REM_score_label==1
                            app.Label_scored(:,:,app.REM_indices)=app.Event_labels(:,:,app.REM_indices);
                        end
                        app.scored_epoch=length(app.Hypnogram);
                        app.scored_epoch_time=app.Epoch_time(end);
                        app.savepoint1=app.scored_epoch;
                        app.temp_tab= squeeze(app.Label_scored(:,:,app.j));
                        uitablehandle = findall(groot,'type','uitable');
                        tt2=(strings(app.Row_num,app.Column_num));
                        tt2(app.temp_tab==1)="A";
                        tt2(app.temp_tab==2)="PR";
                        tt2(app.temp_tab==3)="TR";
                        set(uitablehandle,'Data',tt2)
                        for ii=1:length(app.Evar_name)
                            hold((eval(app.Evar_name{ii})), 'off');
                        end
                        for ii=1:length(app.Evar_name_other)
                            hold((eval(app.Evar_name_other{ii})), 'off');
                        end
                        app.temp_high=1;
                        Epoch_plot(app);
                        high_auto(app);
                        app.j=1;
                    else
                        if app.REM_score_label==1
                            app.Label_scored=app.Event_labels;
                        else
                            app.Label_scored=zeros(app.Row_num,app.Column_num,app.epoch_len);
                        end
                        % call epoch function...plots the first epoch and the first
                        % segment and hightlights the first segment
                        app.scored_epoch=length(app.Hypnogram);
                        app.scored_epoch_time=app.Epoch_time(end);
                        app.savepoint1=app.scored_epoch;
                        app.temp_tab= squeeze(app.Label_scored(:,:,app.j));
                        uitablehandle = findall(groot,'type','uitable');
                        tt2=(strings(app.Row_num,app.Column_num));
                        set(uitablehandle,'Data',tt2)
                        for ii=1:length(app.Evar_name)
                            hold((eval(app.Evar_name{ii})), 'off');
                        end
                        for ii=1:length(app.Evar_name_other)
                            hold((eval(app.Evar_name_other{ii})), 'off');
                        end
                        app.temp_high=1;
                        Epoch_plot(app);
                        high_auto(app);
                        app.j=1;
                    end
                    clear threshold_labels
                else
                    fig = uifigure;
                    fig.Position = [500 500 700 200];
                    fig.WindowStyle = 'alwaysontop';
                    message = "The App requires the sampling frequency of EEG and EOG to be identical to process the data";
                    uialert(fig,message,"Data Checks in Progress","Icon","error");
                    pause(3);
                    close(fig);
                end
            app.OpendeletedtableButton.Enable='on';
            app.dname_auto= uigetdir(pwd, 'Choose the directory to save the scored labels');
            mkdir(app.dname_auto,"ScoreREMdatafiles")
            app.dname_auto=fullfile(app.dname_auto,"ScoreREMdatafiles");
            %% display info
            displayinfo(app);
            %
            figure(app.ScoreREMGUIGNUGPL30UIFigure);
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %% Next
            %             if app.val==0
            if app.j<app.epoch_len
                app.jj=0;update_seg(app);
                app.temp_high=1;
                % making sure the data is not lost when moving back and
                % forth
                if app.j<app.savepoint1
                    app.Label_scored(:,:,app.j)=(app.temp_tab);
                    app.scored_epoch=app.j;
                    app.scored_epoch_time=app.Epoch_time(app.j);
                    app.j=app.j+1;
                    % refresh the plots
                    for ii=1:length(app.Evar_name)
                        hold((eval(app.Evar_name{ii})), 'off');
                    end
                    for ii=1:length(app.Evar_name_other)
                        hold((eval(app.Evar_name_other{ii})), 'off');
                    end
                    app.temp_tab= squeeze(app.Label_scored(:,:,app.j));
                    Epoch_plot(app);
                    %
                    autosave(app);
                    if ~isempty(all(app.temp_tab))
                        all_Seg_ind=find(all(app.temp_tab==1));
                        all_Ch_ind=find(all(app.temp_tab==1,2));
                        if ~isempty(all_Ch_ind)
                            for ii=1:length(all_Ch_ind)
                                app.chan_all=all_Ch_ind(ii);
                                highlight_chan(app)
                            end
                        end
                        if ~isempty(all_Seg_ind)
                            for ii=1:length(all_Seg_ind)
                                app.seg_all=all_Seg_ind(ii);
                                highlight_seg(app)
                            end
                        end
                        %
                        [c]=find(app.temp_tab'==1);
                        [r(:,1),r(:,2)]=find(app.temp_tab==1);
                        if length(c)==length(r)
                            if ~isempty(c)
                                for ii=1:length(c)
                                    if ~ismember(r(ii,1),all_Ch_ind)
                                        app.ichan=r(ii,1);
                                    else
                                        app.ichan=0;
                                    end
                                    if ~ismember(r(ii,2),all_Seg_ind)
                                        app.iseg=r(ii,2);
                                    else
                                        app.iseg=0;
                                    end
                                    if (app.ichan~=0&&app.iseg~=0)
                                        highlight(app)
                                    end
                                end
                            end
                        end
                        %% if EOG/REM
                        app.temp_high=2;
                        for ii=1:length(app.Evar_name_other)
                            hold((eval(app.Evar_name_other{ii})), 'on');
                        end
                        [c1]=find(app.temp_tab'==2);
                        [r1(:,1),r1(:,2)]=find(app.temp_tab==2);
                        if length(c1)==length(r1)
                            if ~isempty(c1)
                                for ii=1:length(c1)
                                    if ~ismember(r1(ii,1),all_Ch_ind)
                                        app.ichan=r1(ii,1);
                                    else
                                        app.ichan=0;
                                    end
                                    if ~ismember(r1(ii,2),all_Seg_ind)
                                        app.iseg=r1(ii,2);
                                    else
                                        app.iseg=0;
                                    end
                                    if (app.ichan~=0&&app.iseg~=0)
                                        highlight_REM(app)
                                    end
                                end
                            end
                        end 
                        app.temp_high=3;
                        for ii=1:length(app.Evar_name_other)
                            hold((eval(app.Evar_name_other{ii})), 'on');
                        end
                        [c2]=find(app.temp_tab'==3);
                        [r2(:,1),r2(:,2)]=find(app.temp_tab==3);
                        if length(c2)==length(r2)
                            if ~isempty(c2)
                                for ii=1:length(c2)
                                    if ~ismember(r2(ii,1),all_Ch_ind)
                                        app.ichan=r2(ii,1);
                                    else
                                        app.ichan=0;
                                    end
                                    if ~ismember(r2(ii,2),all_Seg_ind)
                                        app.iseg=r2(ii,2);
                                    else
                                        app.iseg=0;
                                    end
                                    if (app.ichan~=0&&app.iseg~=0)
                                        highlight_REM2(app)
                                    end
                                end
                            end
                        end
                    end
                    % When going through new epochs
                else
                    autosave(app);
                    app.Label_scored(:,:,app.j)=(app.temp_tab);
                    app.scored_epoch=app.j;
                    app.scored_epoch_time=app.Epoch_time(app.j);
                    app.j=app.j+1;
                    for ii=1:length(app.Evar_name)
                        hold((eval(app.Evar_name{ii})), 'off');
                    end
                    for ii=1:length(app.Evar_name_other)
                        hold((eval(app.Evar_name_other{ii})), 'off');
                    end
                    app.temp_tab=zeros(app.Row_num,app.Column_num);
                    Epoch_plot(app);

                end
            else
                fig = uifigure;
                fig.WindowStyle = 'alwaysontop';
                app.Label_scored(:,:,app.j)=(app.temp_tab);
                app.scored_epoch=app.j;
                app.scored_epoch_time=app.Epoch_time(app.j);
                uialert(fig,'You have reached the end, click "Previous" or "save and exit" button','Reached end');
                pause(3);
                close(fig);
            end
            %             %             end
            cla(app.UIAxes10)
            %             cla(app.UIAxes3)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %% Previous
            %             if app.val==0
            if app.j>1
                app.jj=0;
                update_seg(app)
                app.temp_high=1;
                if app.scored_epoch>app.savepoint1
                    app.savepoint1=app.j;
                end
                app.j=app.j-1;
                app.temp_tab= squeeze(app.Label_scored(:,:,app.j));
                for ii=1:length(app.Evar_name)
                    hold((eval(app.Evar_name{ii})), 'off');
                end
                for ii=1:length(app.Evar_name_other)
                    hold((eval(app.Evar_name_other{ii})), 'off');
                end
                Epoch_plot(app);
                % all chan or segment
                if ~isempty(all(app.temp_tab))
                    all_Seg_ind=find(all(app.temp_tab==1));
                    all_Ch_ind=find(all(app.temp_tab==1,2));
                    if ~isempty(all_Ch_ind)
                        for ii=1:length(all_Ch_ind)
                            app.chan_all=all_Ch_ind(ii);
                            highlight_chan(app)
                        end
                    end
                    if ~isempty(all_Seg_ind)
                        for ii=1:length(all_Seg_ind)
                            app.seg_all=all_Seg_ind(ii);
                            highlight_seg(app)
                        end
                    end
                    %
                    [c]=find(app.temp_tab'==1);
                    [r(:,1),r(:,2)]=find(app.temp_tab==1);
                    if length(c)==length(r)
                        if ~isempty(c)
                            for ii=1:length(c)
                                if ~ismember(r(ii,1),all_Ch_ind)
                                    app.ichan=r(ii,1);
                                else
                                    app.ichan=0;
                                end
                                if ~ismember(r(ii,2),all_Seg_ind)
                                    app.iseg=r(ii,2);
                                else
                                    app.iseg=0;
                                end
                                if (app.ichan~=0&&app.iseg~=0)
                                    highlight(app)
                                end
                            end
                        end
                    end
                    %% if EOG/REM
                    app.temp_high=2;
                    for ii=1:length(app.Evar_name_other)
                        hold((eval(app.Evar_name_other{ii})), 'on');
                    end
                    [c1]=find(app.temp_tab'==2);
                    [r1(:,1),r1(:,2)]=find(app.temp_tab==2);
                    if length(c1)==length(r1)
                        if ~isempty(c1)
                            for ii=1:length(c1)
                                if ~ismember(r1(ii,1),all_Ch_ind)
                                    app.ichan=r1(ii,1);
                                else
                                    app.ichan=0;
                                end
                                if ~ismember(r1(ii,2),all_Seg_ind)
                                    app.iseg=r1(ii,2);
                                else
                                    app.iseg=0;
                                end
                                if (app.ichan~=0&&app.iseg~=0)
                                    highlight_REM(app)
                                end
                            end
                        end
                    end
                    %
                    app.temp_high=3;

                    [c2]=find(app.temp_tab'==3);
                    [r2(:,1),r2(:,2)]=find(app.temp_tab==3);
                    if length(c2)==length(r2)
                        if ~isempty(c2)
                            for ii=1:length(c2)
                                if ~ismember(r2(ii,1),all_Ch_ind)
                                    app.ichan=r2(ii,1);
                                else
                                    app.ichan=0;
                                end
                                if ~ismember(r2(ii,2),all_Seg_ind)
                                    app.iseg=r2(ii,2);
                                else
                                    app.iseg=0;
                                end
                                if (app.ichan~=0&&app.iseg~=0)
                                    highlight_REM2(app)
                                end
                            end
                        end
                    end
                end
                %
            else
                fig = uifigure;
                fig.WindowStyle = 'alwaysontop';
                uialert(fig,'You have reached the start, click "Next"','Reached start');
                pause(3);
                close(fig);
            end
            cla(app.UIAxes10)
            %                         cla(app.UIAxes3)
        end

        % Button pushed function: OpendeletedtableButton
        function OpendeletedtableButtonPushed(app, event)
            uitablehandle = findall(groot,'type','uitable');
            if isempty(uitablehandle)
                tt=array2table(strings(app.Row_num,app.Column_num));
                tt.Properties.RowNames={'EOG-L','EOG-R'};
                tt.Properties.VariableNames=cellstr(string(1:1:app.epoch_segs));

                app.Epoch_labels=tt;
                ft=uifigure;
                ft.Position=[20 20 600 250];
                app.tab = uitable(ft,'Data',app.Epoch_labels,'ColumnWidth',{50 50 50 50 50 50 50 50 50 50});
                tab_t=app.tab;
                tab_t.RowName={'EOG-L','EOG-R'};
                tab_t.Position=[20 20 555 200];
                if app.j~=1
                    uitablehandle = findall(groot,'type','uitable');
                    app.temp_tab= squeeze(app.Label_scored(:,:,app.j));
                    tt2=(strings(app.Row_num,app.Column_num));
                    tt2(app.temp_tab==1)="A";
                    tt2(app.temp_tab==2)="PR";
                    tt2(app.temp_tab==3)="TR";
                    set(uitablehandle,'Data',tt2);
                end
            end
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            xlim((eval(app.Evar_name_other{3})),[app.time_e(1) app.time_e(end)]);
            ylim((eval(app.Evar_name_other{3})),[-app.mi_EMG app.mi_EMG]);
        end

        % Button pushed function: RButton_9
        function RButton_9Pushed(app, event)
            xlim((eval(app.Evar_name_other{1})),[app.time_e(1) app.time_e(end)]);
            ylim((eval(app.Evar_name_other{1})),[-app.mi_EOG app.mi_EOG]);
        end

        % Button pushed function: RButton_8
        function RButton_8Pushed(app, event)
            xlim((eval(app.Evar_name_other{2})),[app.time_e(1) app.time_e(end)]);
            ylim((eval(app.Evar_name_other{2})),[-app.mi_EOG app.mi_EOG]);
        end

        % Button pushed function: RButton_7
        function RButton_7Pushed(app, event)
            xlim((eval(app.Evar_name{1})),[app.time_e(1) app.time_e(end)]);
            ylim((eval(app.Evar_name{1})),[-app.mi_EEG app.mi_EEG]);
        end

        % Button pushed function: RButton_6
        function RButton_6Pushed(app, event)
            xlim((eval(app.Evar_name{2})),[app.time_e(1) app.time_e(end)]);
            ylim((eval(app.Evar_name{2})),[-app.mi_EEG app.mi_EEG]);
        end

        % Button pushed function: RButton_5
        function RButton_5Pushed(app, event)
            xlim((eval(app.Evar_name{3})),[app.time_e(1) app.time_e(end)]);
            ylim((eval(app.Evar_name{3})),[-app.mi_EEG app.mi_EEG]);
        end

        % Button pushed function: RButton_4
        function RButton_4Pushed(app, event)
            xlim((eval(app.Evar_name{4})),[app.time_e(1) app.time_e(end)]);
            ylim((eval(app.Evar_name{4})),[-app.mi_EEG app.mi_EEG]);
        end

        % Button pushed function: RButton_3
        function RButton_3Pushed(app, event)
            xlim((eval(app.Evar_name{5})),[app.time_e(1) app.time_e(end)]);
            ylim((eval(app.Evar_name{5})),[-app.mi_EEG app.mi_EEG]);
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            xlim((eval(app.Evar_name{6})),[app.time_e(1) app.time_e(end)]);
            ylim((eval(app.Evar_name{6})),[-app.mi_EEG app.mi_EEG]);
        end

        % Close request function: ScoreREMGUIGNUGPL30UIFigure
        function ScoreREMGUIGNUGPL30UIFigureCloseRequest(app, event)
            fig = uifigure;
            fig.Position = [500 500 700 200];
            fig.WindowStyle = 'alwaysontop';
            selection = uiconfirm(fig,'Close App without saving?. To save press SAVE&CLOSE','Confirm Close','Icon','warning');
            close(fig);
            if strcmp(selection,'OK')
                delete(app)
            end
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            if (isprop(app, 'edf_full') && isempty(app.edf_full))||(isprop(app, 'Y') && isempty(app.Y))
                fig1 = uifigure;
                fig1.Position = [500 500 700 200];
                fig1.WindowStyle = 'alwaysontop';
                selection1=uiconfirm(fig1,'No Data Loaded','Confirm Close','Icon','warning');
                if strcmp(selection1,'OK')||strcmp(selection1,'Cancel')
                    close(fig1);
                end
            else
                autosave(app);
                if app.goen==0
                    app.val=app.j;
                    app.goen=1;
                    app.RETURNButton.Enable='on';
                    app.jj=0; update_seg(app);
                end
                app.jj=0; update_seg(app);
                app.Label_scored(:,:,app.j)=(app.temp_tab);
                app.j=find(contains(string(app.Epoch_time),eraseBetween(string(app.GoToEpochDropDown.Value)," [","]",'Boundaries','inclusive')));
                app.temp_tab= squeeze(app.Label_scored(:,:,app.j));
                uitablehandle = findall(groot,'type','uitable');
                tt2=(strings(app.Row_num,app.Column_num));
                tt2(app.temp_tab==1)="A";
                tt2(app.temp_tab==2)="PR";
                tt2(app.temp_tab==3)="TR";
                set(uitablehandle,'Data',tt2)
                for ii=1:length(app.Evar_name)
                    hold((eval(app.Evar_name{ii})), 'off');
                end
                for ii=1:length(app.Evar_name_other)
                    hold((eval(app.Evar_name_other{ii})), 'off');
                end
                app.temp_high=1;
                Epoch_plot(app);
                % all chan or segment
                if ~isempty(all(app.temp_tab))
                    all_Seg_ind=find(all(app.temp_tab==1));
                    all_Ch_ind=find(all(app.temp_tab==1,2));
                    if ~isempty(all_Ch_ind)
                        for ii=1:length(all_Ch_ind)
                            app.chan_all=all_Ch_ind(ii);
                            highlight_chan(app)
                        end
                    end
                    if ~isempty(all_Seg_ind)
                        for ii=1:length(all_Seg_ind)
                            app.seg_all=all_Seg_ind(ii);
                            highlight_seg(app)
                        end
                    end
                    %
                    [c]=find(app.temp_tab'==1);
                    [r(:,1),r(:,2)]=find(app.temp_tab==1);
                    if length(c)==length(r)
                        if ~isempty(c)
                            for ii=1:length(c)
                                if ~ismember(r(ii,1),all_Ch_ind)
                                    app.ichan=r(ii,1);
                                else
                                    app.ichan=0;
                                end
                                if ~ismember(r(ii,2),all_Seg_ind)
                                    app.iseg=r(ii,2);
                                else
                                    app.iseg=0;
                                end
                                if (app.ichan~=0&&app.iseg~=0)
                                    highlight(app)
                                end
                            end
                        end
                    end
                    %
                    app.temp_high=2;
                    for ii=1:length(app.Evar_name_other)
                        hold((eval(app.Evar_name_other{ii})), 'on');
                    end
                    [c1]=find(app.temp_tab'==2);
                    [r1(:,1),r1(:,2)]=find(app.temp_tab==2);
                    if length(c1)==length(r1)
                        if ~isempty(c1)
                            for ii=1:length(c1)
                                if ~ismember(r1(ii,1),all_Ch_ind)
                                    app.ichan=r1(ii,1);
                                else
                                    app.ichan=0;
                                end
                                if ~ismember(r1(ii,2),all_Seg_ind)
                                    app.iseg=r1(ii,2);
                                else
                                    app.iseg=0;
                                end
                                if (app.ichan~=0&&app.iseg~=0)
                                    highlight_REM(app)
                                end
                            end
                        end
                    end
                    %
                end
            end
        end

        % Button pushed function: RETURNButton
        function RETURNButtonPushed(app, event)
            app.Label_scored(:,:,app.j)=(app.temp_tab);
            app.j=app.val;
            app.temp_tab= squeeze(app.Label_scored(:,:,app.j));
            app.goen=0;
            app.jj=0; update_seg(app);
            uitablehandle = findall(groot,'type','uitable');
            tt2=(strings(app.Row_num,app.Column_num));
            tt2(app.temp_tab==1)="A";
            tt2(app.temp_tab==2)="PR";
            tt2(app.temp_tab==3)="TR";
            set(uitablehandle,'Data',tt2)
            for ii=1:length(app.Evar_name)
                hold((eval(app.Evar_name{ii})), 'off');
            end
            for ii=1:length(app.Evar_name_other)
                hold((eval(app.Evar_name_other{ii})), 'off');
            end
            app.jj=0;update_seg(app);
            app.temp_high=1;
            Epoch_plot(app);
            % all chan or segment
            if ~isempty(all(app.temp_tab))
                all_Seg_ind=find(all(app.temp_tab==1));
                all_Ch_ind=find(all(app.temp_tab==1,2));
                if ~isempty(all_Ch_ind)
                    for ii=1:length(all_Ch_ind)
                        app.chan_all=all_Ch_ind(ii);
                        highlight_chan(app)
                    end
                end
                if ~isempty(all_Seg_ind)
                    for ii=1:length(all_Seg_ind)
                        app.seg_all=all_Seg_ind(ii);
                        highlight_seg(app)
                    end
                end
                %
                [c]=find(app.temp_tab'==1);
                [r(:,1),r(:,2)]=find(app.temp_tab==1);
                if length(c)==length(r)
                    if ~isempty(c)
                        for ii=1:length(c)
                            if ~ismember(r(ii,1),all_Ch_ind)
                                app.ichan=r(ii,1);
                            else
                                app.ichan=0;
                            end
                            if ~ismember(r(ii,2),all_Seg_ind)
                                app.iseg=r(ii,2);
                            else
                                app.iseg=0;
                            end
                            if (app.ichan~=0&&app.iseg~=0)
                                highlight(app)
                            end
                        end
                    end
                end
                %
                for ii=1:length(app.Evar_name_other)
                    hold((eval(app.Evar_name_other{ii})), 'on');
                end
                app.temp_high=2;
                [c1]=find(app.temp_tab'==2);
                [r1(:,1),r1(:,2)]=find(app.temp_tab==2);
                if length(c1)==length(r1)
                    if ~isempty(c1)
                        for ii=1:length(c1)
                            if ~ismember(r1(ii,1),all_Ch_ind)
                                app.ichan=r1(ii,1);
                            else
                                app.ichan=0;
                            end
                            if ~ismember(r1(ii,2),all_Seg_ind)
                                app.iseg=r1(ii,2);
                            else
                                app.iseg=0;
                            end
                            if (app.ichan~=0&&app.iseg~=0)
                                highlight_REM(app)
                            end
                        end
                    end
                end
                %
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            % Save & close
            if (isprop(app, 'edf_full') && isempty(app.edf_full))||(isprop(app, 'Y') && isempty(app.Y))
                fig1 = uifigure;
                fig1.Position = [500 500 700 200];
                fig1.WindowStyle = 'alwaysontop';
                selection1=uiconfirm(fig1,'No Data Loaded','Confirm Close','Icon','warning');
                if strcmp(selection1,'OK')||strcmp(selection1,'Cancel')
                    close(fig1);
                end
            else
                %
                dname =app.dname_auto;
                % dname = uigetdir(pwd, 'Choose the directory to save the scored labels');
                % %
                % if ~ischar(dname)
                %     f = uifigure;
                %     f.Position = [500 500 700 200];
                %     f.WindowStyle = 'alwaysontop';
                %     selection1=uiconfirm(f,'User pressed Cancel while selecting the file containing data segments','Error','Icon','warning');
                %     if strcmp(selection1,'OK')||strcmp(selection1,'Cancel')
                %         close(f);
                %     end
                %     return;  %
                % else
                    %
                    f = uifigure;
                    f.Position = [500 500 700 200];
                    f.WindowStyle = 'alwaysontop';
                    d = uiprogressdlg(f,'Title','Save the Data Please Wait','Indeterminate','on');
                    name=app.FileNameEditField.Value;
                    name=erase(name,'.edf');
                    Labels=app.Label_scored;
                    Final_epoch=app.scored_epoch;
                    Final_epoch_time=app.scored_epoch_time;

                    Hypnogram_temp=app.Hypnogram;
                    valueSet= (["N3","N2","N1","R","W","A"]);
                    keySet= [1 2 3 4 5 6];
                    M = containers.Map(keySet,valueSet);
                    Hypnogram_string=string(arrayfun(@(x)(M(x)),Hypnogram_temp,'UniformOutput',false));
                    Hypnogram_Tab=timetable(Hypnogram_string,'RowTimes',app.Epoch_time);
                    REM_index=app.REM_indices;
                    save(strcat(dname,'\',name,'_scoredREMlabels_',string(datetime(now,'ConvertFrom','datenum','Format','yyyy-MM-dd''T''HHmmss')),'.mat'),'Labels','Final_epoch','Final_epoch_time','Hypnogram_Tab','REM_index');
                    
                    
                    %% Generate Excel sheets
                    % excel sheet containing the final spectrums per
                    % channel and another sheet containing just labels
                    %                     channels=app.channel_names;
                    labels=app.Label_scored;
                    hyp_string=["Epoch Time","Sleep stage","Epoch No."];

                    Final=[strcat('Final Saved Epoch:',string(Final_epoch));strcat('Final Saved Epoch Time:',string(Final_epoch_time))];
                    %
                    Output_label=fullfile(dname,strcat(name,'_REMLabels_sheet.xlsx'));
                    writematrix(hyp_string,Output_label,'Sheet','Hypnogram','Range','A1');
                    writematrix(app.Epoch_time,Output_label,'Sheet','Hypnogram','Range','A2');
                    writematrix(Hypnogram_string,Output_label,'Sheet','Hypnogram','Range','B2');
                    writematrix((1:length(app.Epoch_time))',Output_label,'Sheet','Hypnogram','Range','C2');
                    writematrix(Final,Output_label,'Sheet','Hypnogram','Range','F2');      
                    writematrix(["Label:Label-Num";"Unscored:'0'";"Artefact:'1'";"Phasic REM:'2'";"Tonic REM:'3'"],Output_label,'Sheet','Hypnogram','Range','F8');
                    rows_rem={'EOG-L-NUM';'EOG-R-NUM'};
                    for ii=1:length(rows_rem)
                        labels_art=squeeze(labels(ii,:,:));
                        e_string=['Epoch No. | Seg. No.';string(1:app.Column_num)']';
                        epoch_string=string(1:1:size(labels_art,2));
                        %
                        writematrix(e_string,Output_label,'Sheet',rows_rem{ii},'Range','A1');
                        writematrix(epoch_string',Output_label,'Sheet',rows_rem{ii},'Range','A2');
                        writematrix(labels_art',Output_label,'Sheet',rows_rem{ii},'Range','B2');
                    end
                    rows_rem={'EOG-L-STR';'EOG-R-STR'};
                    for ii=1:length(rows_rem)
                        labels_art=squeeze(labels(ii,:,:));
                        e_string=['Epoch No. | Seg. No.';string(1:app.Column_num)']';
                        epoch_string=string(1:1:size(labels_art,2));
                        labels_art_str=strings(size(labels_art));
                        labels_art_str(labels_art==0)="Unscored";
                        labels_art_str(labels_art==1)="Artefact";
                        labels_art_str(labels_art==2)="Phasic REM";
                        labels_art_str(labels_art==3)="Tonic REM";
                        %
                        writematrix(e_string,Output_label,'Sheet',rows_rem{ii},'Range','A1');
                        writematrix(epoch_string',Output_label,'Sheet',rows_rem{ii},'Range','A2');
                        writematrix(labels_art_str',Output_label,'Sheet',rows_rem{ii},'Range','B2');
                    end
                    %
                    delete(strcat(dname,'\',name,'_scoredREMlabels_auto.mat'));
                    close(d);close(f);
                    uitablehandle = findall(groot,'type','uitable');
                    if ~isempty(uitablehandle)
                       % Loop through each handle and delete it
                       for iloop = 1:length(uitablehandle)
                            delete(uitablehandle(iloop));
                       end 
                    end
                    % Find all uifigure handles
                    uifigureHandles = findall(groot, 'Type', 'figure', 'Tag', 'UIFigure');
                    % Check if any uifigure handles were found
                    if ~isempty(uifigureHandles)
                        % Loop through each handle and delete it
                        for iloop = 1:length(uifigureHandles)
                            delete(uifigureHandles(iloop));
                        end
                    end
                    fig = uifigure;
                    fig.Position = [500 500 700 200];
                    fig.WindowStyle = 'alwaysontop';
                    selection = uiconfirm(fig,'Close App?','Confirm Close','Icon','warning');
                    close(fig);
                    if strcmp(selection,'OK')
                        delete(app);
                    end
                % end
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            %% Reload
            if (isprop(app, 'edf_full') && isempty(app.edf_full))||(isprop(app, 'Y') && isempty(app.Y))
                fig1 = uifigure;
                fig1.Position = [500 500 700 200];
                fig1.WindowStyle = 'alwaysontop';
                selection1=uiconfirm(fig1,'No Data Loaded','Confirm Close','Icon','warning');
                if strcmp(selection1,'OK')||strcmp(selection1,'Cancel')
                    close(fig1);
                end
                figure(app.ScoreREMGUIGNUGPL30UIFigure);
            else
                [FileName, PathName] = uigetfile('*.mat', 'Select the .MAT file containing the saved labels:');
                if ~ischar(FileName)
                    f = uifigure;
                    f.Position = [500 500 700 200];
                    f.WindowStyle = 'alwaysontop';
                    selection2=uiconfirm(f,'User pressed Cancel while selecting the file containing the saved labels','Error','Icon','warning');
                    if strcmp(selection2,'OK')||strcmp(selection2,'Cancel')
                        close(f);
                    end
                    return;  % Or what ever is applicable
                end
                Prof_filename = fullfile(PathName, FileName);
                figure(app.ScoreREMGUIGNUGPL30UIFigure);
                % Load data and retriving fixed values
                Data=matfile(Prof_filename);
                app.Label_scored=Data.Labels;
                app.scored_epoch=Data.Final_epoch;
                app.scored_epoch_time=Data.Final_epoch_time;
                app.REM_indices=Data.REM_index;
                clear Data
                app.j=app.scored_epoch;
                app.savepoint1=app.scored_epoch;
                app.REM_i=0;

                f = uifigure;
                f.Position = [500 500 700 200];
                f.WindowStyle = 'alwaysontop';
                d = uiprogressdlg(f,'Title','Loading the save labels. Please Wait...','Indeterminate','on');

                for ii=1:length(app.Evar_name)
                    hold((eval(app.Evar_name{ii})), 'off');
                end

                for ii=1:length(app.Evar_name_other)
                    hold((eval(app.Evar_name_other{ii})), 'off');
                end


                app.temp_tab= squeeze(app.Label_scored(:,:,app.j));
                app.temp_high=1;app.jj=0; update_seg(app);
                Epoch_plot(app);
                cla(app.UIAxes10)

                %
                if ~isempty(all(app.temp_tab))
                    all_Seg_ind=find(all(app.temp_tab==1));
                    all_Ch_ind=find(all(app.temp_tab==1,2));
                    %                     if app.tabON==1
                    if ~isempty(all_Ch_ind)
                        for ii=1:length(all_Ch_ind)
                            app.chan_all=all_Ch_ind(ii);
                            highlight_chan(app)
                        end
                    end
                    if ~isempty(all_Seg_ind)
                        for ii=1:length(all_Seg_ind)
                            app.seg_all=all_Seg_ind(ii);
                            highlight_seg(app)
                        end
                    end
                    %                     end
                    %
                    [c]=find(app.temp_tab'==1);
                    [r(:,1),r(:,2)]=find(app.temp_tab==1);
                    if length(c)==length(r)
                        if ~isempty(c)
                            for ii=1:length(c)
                                if ~ismember(r(ii,1),all_Ch_ind)
                                    app.ichan=r(ii,1);
                                else
                                    app.ichan=0;
                                end
                                if ~ismember(r(ii,2),all_Seg_ind)
                                    app.iseg=r(ii,2);
                                else
                                    app.iseg=0;
                                end
                                if (app.ichan~=0&&app.iseg~=0)
                                    highlight(app)
                                end
                            end
                        end
                    end
                    [c1]=find(app.temp_tab'==2);
                    [r1(:,1),r1(:,2)]=find(app.temp_tab==2);
                    if length(c1)==length(r1)
                        if ~isempty(c1)
                            for ii=1:length(c1)
                                if ~ismember(r1(ii,1),all_Ch_ind)
                                    app.ichan=r1(ii,1);
                                else
                                    app.ichan=0;
                                end
                                if ~ismember(r1(ii,2),all_Seg_ind)
                                    app.iseg=r1(ii,2);
                                else
                                    app.iseg=0;
                                end
                                if (app.ichan~=0&&app.iseg~=0)
                                    app.temp_high=2;
                                    highlight_REM(app)
                                end
                            end
                        end
                    end


                end
                close(d);
                close(f);
                figure(app.ScoreREMGUIGNUGPL30UIFigure);
            end
        end

        % Key press function: ScoreREMGUIGNUGPL30UIFigure
        function ScoreREMGUIGNUGPL30UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    Button_6Pushed(app, matlab.ui.eventdata.ButtonPushedData);
                case 'leftarrow'
                    Button_5Pushed(app, matlab.ui.eventdata.ButtonPushedData);
                case 'b'
                    Button_6Pushed(app, matlab.ui.eventdata.ButtonPushedData);
                case 'c'
                    Button_5Pushed(app, matlab.ui.eventdata.ButtonPushedData);
                case 'i'
                    app.s_en=1;
                    app.jj=1;
                    app.iseg=1;
                    update_seg(app);
                    update_plot(app);
                    for jjj=1:length(app.Evar_name_other)-1
                        a=(eval(app.Evar_name_other{jjj}));
                        xline(a,app.vertlines(app.jj),'-.r');
                    end
                case 'k'
                    app.s_en=0;
                    app.jj=0;
                    app.iseg=0;
                    update_seg(app);
                    for jjj=1:length(app.Evar_name_other)-1
                        a=(eval(app.Evar_name_other{jjj}));
                        xline(a,app.vertlines(1),'-.b');
                    end
                case 'a'
                    if app.jj>0
                        if app.jj==1
                            fig = uifigure;
                            fig.Position = [500 500 700 200];
                            fig.WindowStyle = 'alwaysontop';
                            uialert(fig,'You have reached the start of the epoch, press J to move to the previous epoch or press L to move to next epoch','Reached end');
                            pause(3);
                            close(fig);
                        else
                            app.iseg=app.iseg-1;
                            app.jj=app.jj-1;
                            update_plot(app);
                            update_linesA(app)
                            update_seg(app);
                        end
                    end
                case 'f'
                    if app.jj>0
                        if app.jj==app.epoch_segs
                            fig = uifigure;
                            fig.Position = [500 500 700 200];
                            fig.WindowStyle = 'alwaysontop';
                            uialert(fig,'You have reached the end of the epoch, press J to move to the previous epoch or press L to move to next epoch','Reached end');
                            pause(3);
                            close(fig);
                        else
                            app.iseg=app.iseg+1;
                            app.jj=app.jj+1;
                            update_plot(app);
                            update_linesF(app)
                            update_seg(app);
                        end
                    end
                case 'q'
                    if app.jj>0
                        app.ichan=1;
                        if app.temp_tab(app.ichan,app.iseg)==0
                            app.temp_high=1;
                            app.temp_tab(app.ichan,app.iseg)=1;
                            highlight(app);
                            uitablehandle = findall(groot,'type','uitable');
                            tt2=(strings(app.Row_num,app.Column_num));
                            tt2(app.temp_tab==1)="A";
                            tt2(app.temp_tab==2)="PR";
                            tt2(app.temp_tab==3)="TR";
                            set(uitablehandle,'Data',tt2)
                        else
                            app.temp_tab(app.ichan,app.iseg)=0;
                            app.temp_high=0;
                            highlight(app);
                            uitablehandle = findall(groot,'type','uitable');
                            tt2=(strings(app.Row_num,app.Column_num));
                            tt2(app.temp_tab==1)="A";
                            tt2(app.temp_tab==2)="PR";
                            tt2(app.temp_tab==3)="TR";
                            set(uitablehandle,'Data',tt2)
                        end
                    end
                case 'w'
                    if app.jj>0
                        app.ichan=2;
                        if app.temp_tab(app.ichan,app.iseg)==0
                            app.temp_high=1;
                            app.temp_tab(app.ichan,app.iseg)=1;
                            highlight(app);
                            uitablehandle = findall(groot,'type','uitable');
                            tt2=(strings(app.Row_num,app.Column_num));
                            tt2(app.temp_tab==1)="A";
                            tt2(app.temp_tab==2)="PR";
                            tt2(app.temp_tab==3)="TR";
                            set(uitablehandle,'Data',tt2)
                        else
                            app.temp_tab(app.ichan,app.iseg)=0;
                            app.temp_high=0;
                            highlight(app);
                            uitablehandle = findall(groot,'type','uitable');
                            tt2=(strings(app.Row_num,app.Column_num));
                            tt2(app.temp_tab==1)="A";
                            tt2(app.temp_tab==2)="PR";
                            tt2(app.temp_tab==3)="TR";
                            set(uitablehandle,'Data',tt2)
                        end
                    end
                case 'e'
                    if app.jj>0
                        app.ichan=1;
                        if app.temp_tab(app.ichan,app.iseg)==0
                            app.temp_high=2;
                            app.temp_tab(app.ichan,app.iseg)=2;
                            highlight_REM(app);
                            uitablehandle = findall(groot,'type','uitable');
                            tt2=(strings(app.Row_num,app.Column_num));
                            tt2(app.temp_tab==1)="A";
                            tt2(app.temp_tab==2)="PR";
                            tt2(app.temp_tab==3)="TR";
                            set(uitablehandle,'Data',tt2)
                        else
                            app.temp_high=0;
                            app.temp_tab(app.ichan,app.iseg)=0;
                            highlight_REM(app);
                            uitablehandle = findall(groot,'type','uitable');
                            tt2=(strings(app.Row_num,app.Column_num));
                            tt2(app.temp_tab==1)="A";
                            tt2(app.temp_tab==2)="PR";
                            tt2(app.temp_tab==3)="TR";
                            set(uitablehandle,'Data',tt2)
                        end
                        app.ichan=2;
                        if app.temp_tab(app.ichan,app.iseg)==0
                            app.temp_high=2;
                            app.temp_tab(app.ichan,app.iseg)=2;
                            highlight_REM(app);
                            uitablehandle = findall(groot,'type','uitable');
                            tt2=(strings(app.Row_num,app.Column_num));
                            tt2(app.temp_tab==1)="A";
                            tt2(app.temp_tab==2)="PR";
                            tt2(app.temp_tab==3)="TR";
                            set(uitablehandle,'Data',tt2)
                        else
                            app.temp_high=0;
                            app.temp_tab(app.ichan,app.iseg)=0;
                            highlight_REM(app);
                            uitablehandle = findall(groot,'type','uitable');
                            tt2=(strings(app.Row_num,app.Column_num));
                            tt2(app.temp_tab==1)="A";
                            tt2(app.temp_tab==2)="PR";
                            tt2(app.temp_tab==3)="TR";
                            set(uitablehandle,'Data',tt2)
                        end
                    end
                case 'r'
                    if app.jj>0
                        app.ichan=1;
                        if app.temp_tab(app.ichan,app.iseg)==0
                            app.temp_high=3;
                            app.temp_tab(app.ichan,app.iseg)=3;
                            highlight_REM2(app);
                            uitablehandle = findall(groot,'type','uitable');
                            tt2=(strings(app.Row_num,app.Column_num));
                            tt2(app.temp_tab==1)="A";
                            tt2(app.temp_tab==2)="PR";
                            tt2(app.temp_tab==3)="TR";
                            set(uitablehandle,'Data',tt2)
                        else
                            app.temp_high=0;
                            app.temp_tab(app.ichan,app.iseg)=0;
                            highlight_REM2(app);
                            uitablehandle = findall(groot,'type','uitable');
                            tt2=(strings(app.Row_num,app.Column_num));
                            tt2(app.temp_tab==1)="A";
                            tt2(app.temp_tab==2)="PR";
                            tt2(app.temp_tab==3)="TR";
                            set(uitablehandle,'Data',tt2)
                        end
                        app.ichan=2;
                        if app.temp_tab(app.ichan,app.iseg)==0
                            app.temp_high=3;
                            app.temp_tab(app.ichan,app.iseg)=3;
                            highlight_REM2(app);
                            uitablehandle = findall(groot,'type','uitable');
                            tt2=(strings(app.Row_num,app.Column_num));
                            tt2(app.temp_tab==1)="A";
                            tt2(app.temp_tab==2)="PR";
                            tt2(app.temp_tab==3)="TR";
                            set(uitablehandle,'Data',tt2)
                        else
                            app.temp_high=0;
                            app.temp_tab(app.ichan,app.iseg)=0;
                            highlight_REM2(app);
                            uitablehandle = findall(groot,'type','uitable');
                            tt2=(strings(app.Row_num,app.Column_num));
                            tt2(app.temp_tab==1)="A";
                            tt2(app.temp_tab==2)="PR";
                            tt2(app.temp_tab==3)="TR";
                            set(uitablehandle,'Data',tt2)
                        end
                    end
                case 'l'
                    if app.REM_i<length(app.REM_indices)
                        app.REM_i=app.REM_i+1;
                        REM_Go(app)
                    elseif app.REM_i==length(app.REM_indices)
                        fig = uifigure;
                        fig.Position = [500 500 700 200];
                        fig.WindowStyle = 'alwaysontop';
                        uialert(fig,'You have reached the END of REM epochs','Reached end');
                        pause(3);
                        close(fig);
                    end
                case 'j'
                    if app.REM_i>1
                        app.REM_i=app.REM_i-1;
                        REM_GoBack(app)
                    elseif app.REM_i==1
                        fig = uifigure;
                        fig.Position = [500 500 700 200];
                        fig.WindowStyle = 'alwaysontop';
                        uialert(fig,'You have reached the begining of REM epochs','Reached end');
                        pause(3);
                        close(fig);
                    end
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
           displayinfo(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ScoreREMGUIGNUGPL30UIFigure = uifigure('Visible', 'off');
            app.ScoreREMGUIGNUGPL30UIFigure.Name = 'ScoreREMGUI [GNU-GPL-3.0]';
            app.ScoreREMGUIGNUGPL30UIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'gplv3-or-later.png');
            app.ScoreREMGUIGNUGPL30UIFigure.CloseRequestFcn = createCallbackFcn(app, @ScoreREMGUIGNUGPL30UIFigureCloseRequest, true);
            app.ScoreREMGUIGNUGPL30UIFigure.KeyPressFcn = createCallbackFcn(app, @ScoreREMGUIGNUGPL30UIFigureKeyPress, true);
            app.UIAxes10 = uiaxes(app.ScoreREMGUIGNUGPL30UIFigure);
            xlabel(app.UIAxes10, 'Time (s)')
            ylabel(app.UIAxes10, 'Amplitude (\muV)')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes5_9 = uiaxes(app.ScoreREMGUIGNUGPL30UIFigure);
            app.UIAxes5_9.XTick = [];
            app.UIAxes5_9.XTickLabel = '';
            app.UIAxes5_8 = uiaxes(app.ScoreREMGUIGNUGPL30UIFigure);
            app.UIAxes5_8.XTick = [];
            app.UIAxes5_7 = uiaxes(app.ScoreREMGUIGNUGPL30UIFigure);
            app.UIAxes5_7.XTickLabel = '';
            app.UIAxes5_6 = uiaxes(app.ScoreREMGUIGNUGPL30UIFigure);
            app.UIAxes5_6.XTickLabel = '';
            app.UIAxes5_5 = uiaxes(app.ScoreREMGUIGNUGPL30UIFigure);
            app.UIAxes5_5.XTickLabel = '';
            app.UIAxes5_4 = uiaxes(app.ScoreREMGUIGNUGPL30UIFigure);
            app.UIAxes5_4.XTickLabel = '';
            app.UIAxes5_3 = uiaxes(app.ScoreREMGUIGNUGPL30UIFigure);
            app.UIAxes5_3.XTickLabel = '';
            app.UIAxes5_2 = uiaxes(app.ScoreREMGUIGNUGPL30UIFigure);
            app.UIAxes5_2.XTickLabel = '';
            app.UIAxes5 = uiaxes(app.ScoreREMGUIGNUGPL30UIFigure);
            xlabel(app.UIAxes5, 'Time (s)')
            app.Panel_2 = uipanel(app.ScoreREMGUIGNUGPL30UIFigure);
            app.TimeEditField = uieditfield(app.Panel_2, 'text');
            app.TimeEditFieldLabel = uilabel(app.Panel_2);
            app.TimeEditFieldLabel.Text = 'Time';
            app.VigilanceStateEditFieldLabel = uilabel(app.Panel_2);
            app.VigilanceStateEditFieldLabel.Text = 'Vigilance State';
            app.VigilanceStateEditField = uieditfield(app.Panel_2, 'text');
            app.VigilanceStateEditField.Tooltip = {'Sleep Stage'};
            app.EpochnoEditField = uieditfield(app.Panel_2, 'text');
            app.EpochnoEditField.Tooltip = {'Epoch number starting from the begining of the recording.'};
            app.EpochnoEditFieldLabel = uilabel(app.Panel_2);
            app.EpochnoEditFieldLabel.Text = 'Epoch no.';
            app.RETURNButton = uibutton(app.Panel_2, 'push');
            app.RETURNButton.ButtonPushedFcn = createCallbackFcn(app, @RETURNButtonPushed, true);
            app.RETURNButton.Tooltip = {'Return to Epoch'};
            app.RETURNButton.Text = 'RETURN';
            app.GOButton = uibutton(app.Panel_2, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Tooltip = {'Go to Epoch'};
            app.GOButton.Text = 'GO';
            app.Panel_3 = uipanel(app.Panel_2);
            app.FileNameEditFieldLabel = uilabel(app.Panel_3);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.Panel_3, 'text');
            app.FileNameEditField.Tooltip = {'EDF file name'};
            app.LightsoffEditFieldLabel = uilabel(app.Panel_3);
            app.LightsoffEditFieldLabel.Text = 'Lights off';
            app.LightsoffEditField = uieditfield(app.Panel_3, 'text');
            app.LightsonEditFieldLabel = uilabel(app.Panel_3);
            app.LightsonEditFieldLabel.Text = 'Lights on';
            app.LightsonEditField = uieditfield(app.Panel_3, 'text');
            app.Button_7 = uibutton(app.Panel_3, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_7.Icon = fullfile(pathToMLAPP, 'icons', 'Load.png');
            app.Button_7.Tooltip = {'Load Data'};
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.Panel_3, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_8.Icon = fullfile(pathToMLAPP, 'icons', 'Start.png');
            app.Button_8.Tooltip = {'Start Data Import'};
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.Panel_3, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Icon = fullfile(pathToMLAPP, 'icons', 'Reload.png');
            app.Button_9.Tooltip = {'Reload a saved label .mat file'};
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.Panel_3, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Icon = fullfile(pathToMLAPP, 'icons', 'Save.png');
            app.Button_10.Tooltip = {'Save the labels and export outputs'};
            app.Button_10.Text = '';
            app.OpendeletedtableButton = uibutton(app.Panel_3, 'push');
            app.OpendeletedtableButton.ButtonPushedFcn = createCallbackFcn(app, @OpendeletedtableButtonPushed, true);
            app.OpendeletedtableButton.Tooltip = {'Open the microsegment label table'};
            app.OpendeletedtableButton.Text = 'Open deleted table';
            app.Button_5 = uibutton(app.Panel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'icons', 'Previous.png');
            app.Button_5.Tooltip = {'Previous Epoch'};
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.Panel_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, 'icons', 'Next.png');
            app.Button_6.Tooltip = {'Next Epoch'};
            app.Button_6.Text = '';
            app.EOGuVEditFieldLabel = uilabel(app.Panel_2);
            app.EOGuVEditFieldLabel.Text = 'EOG (uV)';
            app.EOGuVEditField = uieditfield(app.Panel_2, 'numeric');
            app.EOGuVEditField.Limits = [0 500];
            app.EOGuVEditField.ValueDisplayFormat = '%3g';
            app.EOGuVEditField.Tooltip = {'Change to set the EOG magnitude'};
            app.EOGuVEditField.Value = 100;
            app.EEGuVEditFieldLabel = uilabel(app.Panel_2);
            app.EEGuVEditFieldLabel.Text = 'EEG (uV)';
            app.EEGuVEditField = uieditfield(app.Panel_2, 'numeric');
            app.EEGuVEditField.Limits = [0 500];
            app.EEGuVEditField.ValueDisplayFormat = '%3g';
            app.EEGuVEditField.Tooltip = {'Change to set the EEG magnitude'};
            app.EEGuVEditField.Value = 100;
            app.EMGuVEditFieldLabel = uilabel(app.Panel_2);
            app.EMGuVEditFieldLabel.Text = 'EMG(uV)';
            app.EMGuVEditField = uieditfield(app.Panel_2, 'numeric');
            app.EMGuVEditField.Limits = [0 500];
            app.EMGuVEditField.ValueDisplayFormat = '%3g';
            app.EMGuVEditField.Tooltip = {'Change to set the EMG magnitude'};
            app.EMGuVEditField.Value = 100;
            app.GoToEpochDropDownLabel = uilabel(app.Panel_2);
            app.GoToEpochDropDownLabel.Text = 'GoTo Epoch';
            app.GoToEpochDropDown = uidropdown(app.Panel_2);
            app.Button_11 = uibutton(app.Panel_2, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Icon = fullfile(pathToMLAPP, 'icons', 'Info.png');
            app.Button_11.Text = '';
            app.RButton_9 = uibutton(app.ScoreREMGUIGNUGPL30UIFigure, 'push');
            app.RButton_9.ButtonPushedFcn = createCallbackFcn(app, @RButton_9Pushed, true);
            app.RButton_9.Tooltip = {'Reset View'};
            app.RButton_9.Text = 'R';
            app.LOCLabel = uilabel(app.ScoreREMGUIGNUGPL30UIFigure);
            app.LOCLabel.Text = 'LOC';
            app.RButton_8 = uibutton(app.ScoreREMGUIGNUGPL30UIFigure, 'push');
            app.RButton_8.ButtonPushedFcn = createCallbackFcn(app, @RButton_8Pushed, true);
            app.RButton_8.Tooltip = {'Reset View'};
            app.RButton_8.Text = 'R';
            app.ROCLabel = uilabel(app.ScoreREMGUIGNUGPL30UIFigure);
            app.ROCLabel.Text = 'ROC';
            app.RButton_7 = uibutton(app.ScoreREMGUIGNUGPL30UIFigure, 'push');
            app.RButton_7.ButtonPushedFcn = createCallbackFcn(app, @RButton_7Pushed, true);
            app.RButton_7.Tooltip = {'Reset View'};
            app.RButton_7.Text = 'R';
            app.F4M1Label = uilabel(app.ScoreREMGUIGNUGPL30UIFigure);
            app.F4M1Label.Text = 'F4M1';
            app.RButton_6 = uibutton(app.ScoreREMGUIGNUGPL30UIFigure, 'push');
            app.RButton_6.ButtonPushedFcn = createCallbackFcn(app, @RButton_6Pushed, true);
            app.RButton_6.Tooltip = {'Reset View'};
            app.RButton_6.Text = 'R';
            app.C4M1Label = uilabel(app.ScoreREMGUIGNUGPL30UIFigure);
            app.C4M1Label.Text = 'C4M1';
            app.RButton_5 = uibutton(app.ScoreREMGUIGNUGPL30UIFigure, 'push');
            app.RButton_5.ButtonPushedFcn = createCallbackFcn(app, @RButton_5Pushed, true);
            app.RButton_5.Tooltip = {'Reset View'};
            app.RButton_5.Text = 'R';
            app.O2M1Label = uilabel(app.ScoreREMGUIGNUGPL30UIFigure);
            app.O2M1Label.Text = 'O2M1';
            app.RButton_4 = uibutton(app.ScoreREMGUIGNUGPL30UIFigure, 'push');
            app.RButton_4.ButtonPushedFcn = createCallbackFcn(app, @RButton_4Pushed, true);
            app.RButton_4.Tooltip = {'Reset View'};
            app.RButton_4.Text = 'R';
            app.F3M2Label = uilabel(app.ScoreREMGUIGNUGPL30UIFigure);
            app.F3M2Label.Text = 'F3M2';
            app.RButton_3 = uibutton(app.ScoreREMGUIGNUGPL30UIFigure, 'push');
            app.RButton_3.ButtonPushedFcn = createCallbackFcn(app, @RButton_3Pushed, true);
            app.RButton_3.Tooltip = {'Reset View'};
            app.RButton_3.Text = 'R';
            app.C3M2Label = uilabel(app.ScoreREMGUIGNUGPL30UIFigure);
            app.C3M2Label.Text = 'C3M2';
            app.RButton_2 = uibutton(app.ScoreREMGUIGNUGPL30UIFigure, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Tooltip = {'Reset View'};
            app.RButton_2.Text = 'R';
            app.O1M2Label = uilabel(app.ScoreREMGUIGNUGPL30UIFigure);
            app.O1M2Label.Text = 'O1M2';
            app.RButton = uibutton(app.ScoreREMGUIGNUGPL30UIFigure, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Tooltip = {'Reset View'};
            app.RButton.Text = 'R';
            app.EMGLabel = uilabel(app.ScoreREMGUIGNUGPL30UIFigure);
            app.EMGLabel.Text = 'EMG';
            app.Panel_4 = uipanel(app.ScoreREMGUIGNUGPL30UIFigure);
            app.SegmentNumberEditField = uieditfield(app.Panel_4, 'text');
            app.SegmentNumberEditField.Editable = 'off';
            app.SegmentNumberEditField.Tooltip = {'Microsegment number'};
            app.SegmentNumberEditFieldLabel = uilabel(app.Panel_4);
            app.SegmentNumberEditFieldLabel.Text = 'Segment Number';
            % Show the figure after all components are created
            app.ScoreREMGUIGNUGPL30UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScoreREMGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScoreREMGUIGNUGPL30UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScoreREMGUIGNUGPL30UIFigure)
        end
    end
end